classdef runMe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APT_GBUIFigure                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Projection2DTab                 matlab.ui.container.Tab
        Step1ImportDataPanel            matlab.ui.container.Panel
        PosBottom                       matlab.ui.control.Button
        RRNGButton                      matlab.ui.control.Button
        Reduced                         matlab.ui.control.Button
        ReducedpercentageLabel          matlab.ui.control.Label
        ReducedPerc                     matlab.ui.control.Slider
        ReducedPercLab                  matlab.ui.control.Label
        ChemLabel                       matlab.ui.control.Label
        ReducedChem                     matlab.ui.control.DropDown
        ReducedLight                    matlab.ui.control.Lamp
        ReducedAtomIon                  matlab.ui.container.ButtonGroup
        ReducedAtom                     matlab.ui.control.RadioButton
        ReducedIon                      matlab.ui.control.RadioButton
        Step2Proj2DPanel                matlab.ui.container.Panel
        ResolutionnmEditFieldLabel      matlab.ui.control.Label
        Proj2DReso                      matlab.ui.control.NumericEditField
        Proj2DAll                       matlab.ui.control.Button
        FilterLabel                     matlab.ui.control.Label
        Proj2DIF                        matlab.ui.control.Slider
        Proj2DOne                       matlab.ui.control.Button
        GapSliderLabel                  matlab.ui.control.Label
        Proj2DGap                       matlab.ui.control.Slider
        Proj2DIFLab                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Proj2DIonPerc                   matlab.ui.control.Slider
        Proj2DGapLab                    matlab.ui.control.Label
        Proj2DIonPercLab                matlab.ui.control.Label
        Step3SubImagePanel              matlab.ui.container.Panel
        SubimagesizepixelDropDownLabel  matlab.ui.control.Label
        SubImSize                       matlab.ui.control.DropDown
        SubImStore                      matlab.ui.control.Button
        SubImSaveLab                    matlab.ui.control.Label
        SubImSaveLight                  matlab.ui.control.Lamp
        GappixelDropDownLabel           matlab.ui.control.Label
        SubImGap                        matlab.ui.control.DropDown
        SubImInformation                matlab.ui.control.Label
        VersionDropDownLabel            matlab.ui.control.Label
        SubImVersion                    matlab.ui.control.DropDown
        Project2DPanel                  matlab.ui.container.Panel
        Proj2DIm                        matlab.ui.control.UIAxes
        Proj2DICChoice                  matlab.ui.container.ButtonGroup
        Proj2DIC_Filtered               matlab.ui.control.RadioButton
        Proj2DIC_Raw                    matlab.ui.control.RadioButton
        Proj2DIC_FilteredEdge           matlab.ui.control.RadioButton
        Project2DAllPanel               matlab.ui.container.Panel
        Proj2DSRChoice                  matlab.ui.container.ButtonGroup
        Proj2DSR_Both                   matlab.ui.control.RadioButton
        Proj2DSR_Num                    matlab.ui.control.RadioButton
        Proj2DSR_Con                    matlab.ui.control.RadioButton
        PredictThetaIm                  matlab.ui.control.UIAxes
        Proj2DThetaSliderLab            matlab.ui.control.Label
        SelectedThetadegreeLabel        matlab.ui.control.Label
        Proj2DThetaSlider               matlab.ui.control.Slider
        FindGBsTab                      matlab.ui.container.Tab
        Step4GBLinePanel                matlab.ui.container.Panel
        CleanGBSkeletonSliderLabel      matlab.ui.control.Label
        GBLineSkel                      matlab.ui.control.Slider
        TripleImageFilter1SliderLabel   matlab.ui.control.Label
        GBLineTr1                       matlab.ui.control.Slider
        TripleImageFilter2SliderLabel   matlab.ui.control.Label
        GBLineTr2                       matlab.ui.control.Slider
        MinimumLengthnmLabel            matlab.ui.control.Label
        GBLineMinL                      matlab.ui.control.Slider
        GBLineShow                      matlab.ui.control.Button
        GBLineIFLab                     matlab.ui.control.Label
        GBLineSkelLab                   matlab.ui.control.Label
        GBLineTr1Lab                    matlab.ui.control.Label
        GBLineTr2Lab                    matlab.ui.control.Label
        GBLineMinLLab                   matlab.ui.control.Label
        GBImageFilterLabel              matlab.ui.control.Label
        GBLineIF                        matlab.ui.control.Slider
        GBLineMaxLLab                   matlab.ui.control.Label
        MaximumLengthnmLabel            matlab.ui.control.Label
        GBLineMaxL                      matlab.ui.control.Slider
        StyleLabel                      matlab.ui.control.Label
        GBLineStyle                     matlab.ui.control.DropDown
        Step5GenerateGB                 matlab.ui.container.Panel
        GenerateGBOBJ                   matlab.ui.control.Button
        GenerateGBLoad                  matlab.ui.control.Button
        OpenBlenderButton               matlab.ui.control.Button
        FindGBPanel                     matlab.ui.container.Panel
        GBLineGBIm                      matlab.ui.control.UIAxes
        GBLineTrIm                      matlab.ui.control.UIAxes
        GBLineGBIC                      matlab.ui.container.ButtonGroup
        GBLineGBIC_Raw                  matlab.ui.control.RadioButton
        GBLineGBIC_BW                   matlab.ui.control.RadioButton
        GBLineGBIC_Skel                 matlab.ui.control.RadioButton
        GBLineGBIC_GBLines              matlab.ui.control.RadioButton
        GBLineShowLineSegPanel          matlab.ui.container.Panel
        GBLineShowLineSegRaw            matlab.ui.control.CheckBox
        GBLineShowLineSegCorr           matlab.ui.control.CheckBox
        GBLineTrIC                      matlab.ui.container.ButtonGroup
        GBLineTrIC_Raw                  matlab.ui.control.RadioButton
        GBLineTrIC_Filtered             matlab.ui.control.RadioButton
        GBLineTrIC_Con                  matlab.ui.control.RadioButton
        GBLineTrIC_None                 matlab.ui.control.RadioButton
        LoadCNN                         matlab.ui.control.Button
        GBAnalysisTab                   matlab.ui.container.Tab
        Step6MappingPanel               matlab.ui.container.Panel
        MapCal                          matlab.ui.control.Button
        ZLimitnmSliderLabel             matlab.ui.control.Label
        MapZLim                         matlab.ui.control.Slider
        ZwidthnmSliderLabel             matlab.ui.control.Label
        MapZWidth                       matlab.ui.control.Slider
        MapZLimLab                      matlab.ui.control.Label
        MapZWidthLab                    matlab.ui.control.Label
        ChemLabel_2                     matlab.ui.control.Label
        MapChem                         matlab.ui.control.DropDown
        MapIC                           matlab.ui.container.ButtonGroup
        MapIC_Smoothed                  matlab.ui.control.RadioButton
        MapIC_Raw                       matlab.ui.control.RadioButton
        MapStatusLab                    matlab.ui.control.Label
        MapStatusLight                  matlab.ui.control.Lamp
        MapObjFile                      matlab.ui.control.Button
        SystemTypeDropDownLabel         matlab.ui.control.Label
        MapSysType                      matlab.ui.control.DropDown
        MapAtomIon                      matlab.ui.container.ButtonGroup
        MapAtom                         matlab.ui.control.RadioButton
        MapIon                          matlab.ui.control.RadioButton
        MapLoad                         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GBAnaPanel                      matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        OverIESave                      matlab.ui.control.Button
        UpEditFieldLabel                matlab.ui.control.Label
        OverCUp                         matlab.ui.control.NumericEditField
        OverCDw                         matlab.ui.control.NumericEditField
        ColorDwEditFieldLabel           matlab.ui.control.Label
        OverCSave                       matlab.ui.control.Button
        ColorDwEditFieldLabel_2         matlab.ui.control.Label
        OverIEDw                        matlab.ui.control.NumericEditField
        UpEditFieldLabel_2              matlab.ui.control.Label
        OverIEUp                        matlab.ui.control.NumericEditField
        OverIEIm                        matlab.ui.control.UIAxes
        OverCIm                         matlab.ui.control.UIAxes
        OverSyncIE                      matlab.ui.control.Button
        OverSyncC                       matlab.ui.control.Button
        SelectedGBsTab                  matlab.ui.container.Tab
        IndiIC                          matlab.ui.container.ButtonGroup
        IndiIC_Smoothed                 matlab.ui.control.RadioButton
        IndiIC_Raw                      matlab.ui.control.RadioButton
        IndiCal                         matlab.ui.control.Button
        GBNumbersDropDownLabel          matlab.ui.control.Label
        IndiGBNum                       matlab.ui.control.DropDown
        TabGroup3                       matlab.ui.container.TabGroup
        NormalTab_2                     matlab.ui.container.Tab
        IndiIENorIm                     matlab.ui.control.UIAxes
        IndiCNorIm                      matlab.ui.control.UIAxes
        LandscaleTab                    matlab.ui.container.Tab
        IndiIEIm                        matlab.ui.control.UIAxes
        IndiCIm                         matlab.ui.control.UIAxes
        IndiSyncIE                      matlab.ui.control.Button
        IndiSyncC                       matlab.ui.control.Button
        IndiCDw                         matlab.ui.control.NumericEditField
        CmapColorDwLabel                matlab.ui.control.Label
        UpEditFieldLabel_3              matlab.ui.control.Label
        IndiCUp                         matlab.ui.control.NumericEditField
        IndiIEDw                        matlab.ui.control.NumericEditField
        IEmapColorDwLabel               matlab.ui.control.Label
        UpEditFieldLabel_4              matlab.ui.control.Label
        IndiIEUp                        matlab.ui.control.NumericEditField
        IndiSave                        matlab.ui.control.Button
        HistogramTab                    matlab.ui.container.Tab
        HisCIm                          matlab.ui.control.UIAxes
        HisIEIm                         matlab.ui.control.UIAxes
        HisCDw                          matlab.ui.control.NumericEditField
        DwLabel                         matlab.ui.control.Label
        UpEditFieldLabel_5              matlab.ui.control.Label
        HisCUp                          matlab.ui.control.NumericEditField
        HisSave                         matlab.ui.control.Button
        HisCBar                         matlab.ui.control.NumericEditField
        CbarLabel                       matlab.ui.control.Label
        HisIEDw                         matlab.ui.control.NumericEditField
        DwLabel_2                       matlab.ui.control.Label
        UpEditFieldLabel_6              matlab.ui.control.Label
        HisIEUp                         matlab.ui.control.NumericEditField
        HisIEBar                        matlab.ui.control.NumericEditField
        IEbarLabel                      matlab.ui.control.Label
        HisType                         matlab.ui.container.ButtonGroup
        HisAllGBs                       matlab.ui.control.RadioButton
        HisIndiGBs                      matlab.ui.control.RadioButton
        HisMethod                       matlab.ui.container.ButtonGroup
        HisNum                          matlab.ui.control.RadioButton
        HisArea                         matlab.ui.control.RadioButton
        LadderdiagramTab                matlab.ui.container.Tab
        GBNumberLabel                   matlab.ui.control.Label
        LadderGBNum                     matlab.ui.control.DropDown
        LadderPlot                      matlab.ui.control.Button
        ZoomleftclickinShiftleftclickoutORScrollwheelLabel  matlab.ui.control.Label
        LadderZoom                      matlab.ui.control.Switch
        LadderIm                        matlab.ui.control.UIAxes
        AnalysisStatisIm                matlab.ui.control.UIAxes
        PrepareTrainingDatasetOptionalTab  matlab.ui.container.Tab
        Training                        matlab.ui.control.Button
        NumberofGBstructureunitsEditFieldLabel  matlab.ui.control.Label
        TrainingNumOfGBnet              matlab.ui.control.NumericEditField
        RestartpointofGBstructureunitsEditFieldLabel  matlab.ui.control.Label
        TrainingNumOfGBnetRestar        matlab.ui.control.NumericEditField
        NumberofTrainingTestinggroupsDropDownLabel  matlab.ui.control.Label
        TrainingSegment                 matlab.ui.control.DropDown
    end


    properties (Access = public)
        posName           % pos name
        pathPosName       % pos path
        rngName           % rng name
        pathRngName       % rng path
        pathObjName       % Obj path
        objName           % Obj name
        foldName          % Analysis folder name
        
        cnnName           % record file name of cnn
               
        pAll              % atom position all
        pSol              % atom position solute
        mean3DAll         % mean for all data
        std3DAll          % std for all data
        
        ProjResolution    % Image resolution
        
        BW                % Proj2D image
        conProjR          % Raw C map
        conProj           % Proj2D image with NaN
        X                 % Image size X
        Y                 % Image size Y
        xNum              % Num in X
        yNum              % Num in Y
        aThetaAll         % Rotation theta, X axis
        d                 % Rotation theta, Y axis both
        dC                % Rotation theta, Y axis concentration
        dN                % Rotation theta, Y axis number
        PaTheta           % Idea theta found from both concentration & number 
        PCaTheta          % Idea theta found from concentration
        PNaTheta          % Idea theta found from number
        dKey              % value when idea theta found from both concentration & number 
        dCKey             % value when idea theta found from both concentration 
        dNKey             % value when idea theta found from both number 
        
        BWB               % GB found by ML
        BWTr              % Triple found by ML
        BWBN              % Filtered GB image
        BWBNS             % Filtered GB Skeleton
        BWBNSS            % Cleaned GB Skeleton
        BWTrF             % Filtered Tri
        BWTrRegmax        % Tri points
        
        
        vert2D            % vertices for export *.obj
        vert2DRaw         % vertices for export *.obj without triple junction ajustment
        lineSeg           % lines for exprot *.obj
        CCBTrCentroidRaw  % lines without triple junction ajustment for imaging
        CCBTrCentroid     % lines with triple junction correction for imaging
        
        
        GBVal             % Analysis GB values
        Fv                % Analysis GB patch

        SGBVal            % Analysis GB values_Smoothed
        SFv               % Analysis GB patch_Smoothed

        
        fv                % fv for individual patch
        fvN               % fv for individual patch normal
        v                 % Average projection direction for individual patch
        
        DE                % Detector efficiency
        
        allAtomsN         % AllAtoms name
        allIonsN          % AllIons name
        
        viewAngleC        % camera setting for composition map
        viewAngleIE       % camera setting for IE map

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           global ProjResolution
            
           app.RRNGButton.Enable = 0;
           app.ReducedAtom.Enable = 0;
           app.ReducedIon.Enable = 0;
           app.Reduced.Enable = 0;
           app.ReducedPerc.Enable = 0;
           app.ReducedPercLab.Enable = 0;
           app.ReducedChem.Enable = 0;
           app.ReducedLight.Enable = 0;
           
           app.Step2Proj2DPanel.Visible = 0;
           app.Project2DPanel.Visible = 0;
           app.Project2DAllPanel.Visible = 0;
           app.Step3SubImagePanel.Visible = 0;
           
           app.LoadCNN.Visible = 0;
                     
           app.Step4GBLinePanel.Visible = 0;
           app.Step5GenerateGB.Visible = 0;
           app.FindGBPanel.Visible = 0;
           
           app.Step6MappingPanel.Visible = 1;
           app.GBAnaPanel.Visible = 0;
           app.AnalysisStatisIm.Visible = 0;
           
           app.MapCal.Enable = 0;
           app.MapIC_Smoothed.Enable = 0;
           app.MapIC_Raw.Enable = 0;
           
           app.MapSysType.Enable = 0;
           app.MapAtom.Enable = 0;
           app.MapIon.Enable = 0;
           app.MapChem.Enable = 0;
           app.MapZLim.Enable = 0;
           app.MapZWidth.Enable = 0;
           app.MapObjFile.Enable = 0;
           
           app.HisIndiGBs.Visible = 0;
           
           %app.AnalysisNote1.Visible = 0;
           %app.AnalysisNote2.Visible = 0;
           
           addpath(genpath('codes'));
           addpath(genpath('mlGB'));
           addpath(genpath('cnn'));
           addpath(genpath('Blender'));
           
           
           segment = {};
           segmentN = [];
           for i = 1:floor(str2num(app.SubImSize.Value)/4)
              segment{end+1} = num2str(i);
              segmentN(end+1) = i;
           end
           app.SubImGap.Items = segment;
           app.SubImGap.ItemsData = segmentN;
           
           numGBnet = round(app.TrainingNumOfGBnet.Value);
           app.TrainingNumOfGBnet.Value = numGBnet;
           segment = {};
           segmentN = [];
           for i = 1:numGBnet-1
              if mod(numGBnet,i) == 0
                segment{end+1} = num2str(i);
                segmentN(end+1) = numGBnet/i;
              end
           end
           app.TrainingSegment.Items = segment;
           app.TrainingSegment.ItemsData = segmentN;
           
           ProjResolution = app.Proj2DReso.Value;
         
            
        end

        % Button pushed function: PosBottom
        function PosBottomPushed(app, event)
           global posName pathPosName
           
           [posName, pathPosName] = uigetfile('*.pos','Select a file'); 
           app.RRNGButton.Enable = 1;
          
        end

        % Button pushed function: RRNGButton
        function RRNGButtonPushed(app, event)
            global rngName pathRngName allAtomsN allIonsN pathPosName
            
            [rrngName, pathRngName] = uigetfile('*.rrng','Select a file',pathPosName);
            rrngN = [pathRngName rrngName];
            rng = readrrng(rrngN);
            rngName = [rrngName(1:end-4) 'rng'];
            rngN = [pathPosName rngName];
            saveRng_R(rng,rngN);
            pathRngName = pathPosName;
            
            rngXls = rngRead_R(rngN);
            elements = rngXls(1,3:end);
            allAtomsN = elements;
            numEl = length(elements);
            elementCount = cell2mat(rngXls(2:end,3:end));
            [ions, ~, ~] = unique(elementCount,'rows');
            ions = flipud(ions);
            numIon = length(ions(:,1));
            allIonsN = [];
            for i = 1:numIon
                ionName = '';
                for el = 1:numEl
                    if ions(i,el) > 0
                        if ions(i,el) == 1
                            ionName = [ionName, elements{el}];
                        else
                            ionName = [ionName, elements{el}, num2str(ions(i,el))];
                        end
                    end
                end
                allIonsN{i} = ionName; 
            end
            
            app.ReducedChem.Items = allAtomsN;
            app.MapAtom.Value = 1;
            app.MapChem.Items = allAtomsN;
            
            app.ReducedAtom.Enable = 1;
            app.ReducedIon.Enable = 1;            
            app.Reduced.Enable = 1;
            app.ReducedPerc.Enable = 1;
            app.ReducedPercLab.Enable = 1;
            app.ReducedChem.Enable = 1;
            app.ReducedLight.Enable = 1;
            
            app.Step2Proj2DPanel.Visible = 1;
            
            
            app.Proj2DGap.Enable = 0;
            app.Proj2DAll.Enable = 0;
            
            app.MapSysType.Enable = 1;
            app.MapAtom.Enable = 1;
            app.MapIon.Enable = 1;
            app.MapChem.Enable = 1;
            app.MapZLim.Enable = 1;
            app.MapZWidth.Enable = 1;
            app.MapObjFile.Enable = 1;
   
        end

        % Button pushed function: Reduced
        function ReducedPushed(app, event)
            global posName pathPosName rngName pathRngName allAtomsN allIonsN
            app.ReducedLight.Color = [0 1 0];
            wb = waitbar(0,'performing calculation');
            close(wb);
            if app.ReducedAtom.Value == 1
                atomIon = 'atom';
                allN = allAtomsN;
            else
                atomIon = 'ion';
                allN = allIonsN;
            end
            S1_runMeBeforeBlender_R(app.ReducedChem.Value,str2double(app.ReducedPercLab.Text),pathPosName,posName,pathRngName,rngName,atomIon,allN);
            app.ReducedLight.Color = [0.65 0.65 0.65];
        end

        % Callback function: Proj2DIF, Proj2DIonPerc, Proj2DOne, 
        % Proj2DReso
        function Proj2DOneButtonPushed(app, event)
            global posName pathPosName rngName pathRngName pAll pSol mean3DAll std3DAll BW conProjR conProj X Y xNum yNum allAtomsN allIonsN...
                 ProjResolution
             
             if app.ReducedAtom.Value == 1
               allN = allAtomsN;
               atomIon = 'atom';
             elseif app.ReducedIon.Value == 1
               allN = allIonsN;
               atomIon = 'ion';
             end
            
            ProjResolution = app.Proj2DReso.Value;
            
             
            [pAll,pSol,mean3DAll,std3DAll] = project2DPre_R(app.ReducedChem.Value,str2double(app.Proj2DIonPercLab.Text),app.Proj2DReso.Value,...
                                pathPosName,posName,pathRngName,rngName,atomIon,allN);
            
            Proj2DMax = mean3DAll + std3DAll*str2double(app.Proj2DIFLab.Text);
            if Proj2DMax >= 1 || Proj2DMax <= 0
               app.Proj2DIFLab.Text = '0';
               app.Proj2DIF.Value = 0;
            end
                                
            [BW,conProjR,conProj,X,Y,xNum,yNum,~,~,~] = ...
                  project2DEva_R(app.Proj2DThetaSlider.Value,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll);
            app.Proj2DSR_Both.Value = 1;
            surf(app.Proj2DIm,X,Y,conProj)
            shading(app.Proj2DIm,'interp')
            app.Proj2DIm.XLim = [min(X(:)) max(X(:))];
            app.Proj2DIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.Proj2DIm,[xNum yNum 1])
            view(app.Proj2DIm,2) 
            
            app.Project2DPanel.Visible = 1;
            app.Proj2DGap.Enable = 1;
            app.Proj2DAll.Enable = 1;
            
            app.LoadCNN.Visible = 1;
           
        end

        % Selection changed function: Proj2DSRChoice
        function Proj2DSRChoiceSelectionChanged(app, event)
            global pAll pSol mean3DAll std3DAll aThetaAll d dC dN dKey dCKey dNKey PaTheta PCaTheta PNaTheta BW conProjR conProj X Y xNum yNum...
                    ProjResolution
            
            ProjResolution = app.Proj2DReso.Value;
            
            app.Proj2DIC_FilteredEdge.Value = 1;
            if app.Proj2DSR_Both.Value == 1 
              app.Proj2DThetaSlider.Value =  PaTheta;
              app.Proj2DThetaSliderLab.Text = num2str(PaTheta);
              plot(app.PredictThetaIm,aThetaAll,d);
              app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
              app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
              hold(app.PredictThetaIm,'on')
              scatter(app.PredictThetaIm,PaTheta,dKey);
              hold(app.PredictThetaIm,'off')
              [BW,conProjR,conProj,X,Y,xNum,yNum,~,~,~] = ...
                 project2DEva_R(app.Proj2DThetaSlider.Value,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll); 
              surf(app.Proj2DIm,X,Y,conProj)
            elseif app.Proj2DSR_Con.Value == 1 
              app.Proj2DThetaSlider.Value =  PCaTheta;
              app.Proj2DThetaSliderLab.Text = num2str(PCaTheta);
              plot(app.PredictThetaIm,aThetaAll,dC);
              app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
              app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
              hold(app.PredictThetaIm,'on')
              scatter(app.PredictThetaIm,PCaTheta,dCKey);
              hold(app.PredictThetaIm,'off')
              [BW,conProjR,conProj,X,Y,xNum,yNum,~,~,~] = ...
                 project2DEva_R(app.Proj2DThetaSlider.Value,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll); 
              surf(app.Proj2DIm,X,Y,conProj)
            elseif app.Proj2DSR_Num.Value == 1 
              app.Proj2DThetaSlider.Value =  PNaTheta;
              app.Proj2DThetaSliderLab.Text = num2str(PNaTheta);
              plot(app.PredictThetaIm,aThetaAll,dN);
              app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
              app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
              hold(app.PredictThetaIm,'on')
              scatter(app.PredictThetaIm,PNaTheta,dNKey);
              hold(app.PredictThetaIm,'off')
              [BW,conProjR,conProj,X,Y,xNum,yNum,~,~,~] = ...
                 project2DEva_R(app.Proj2DThetaSlider.Value,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll); 
              surf(app.Proj2DIm,X,Y,conProj)
            end
            shading(app.Proj2DIm,'interp')
            app.Proj2DIm.XLim = [min(X(:)) max(X(:))];
            app.Proj2DIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.Proj2DIm,[xNum yNum 1])
            view(app.Proj2DIm,2)    
        end

        % Button pushed function: Proj2DAll
        function Proj2DAllButtonPushed(app, event)
            global  pAll pSol mean3DAll std3DAll aThetaAll d dC dN dKey dCKey dNKey PaTheta PCaTheta PNaTheta BW conProjR conProj X Y xNum yNum...
                    ProjResolution
            
            ProjResolution = app.Proj2DReso.Value;
            
            app.Project2DAllPanel.Visible = 1;
            app.Step3SubImagePanel.Visible = 1;
            
            app.GBLineIF.Enable = 0;
            app.GBLineMaxL.Enable = 0;
            app.GBLineSkel.Enable = 0;
            app.GBLineTr1.Enable = 0;
            app.GBLineTr2.Enable = 0;
            app.GBLineTr2.Enable = 0;
            app.GBLineMinL.Enable =0;     
            
            d = []; dC = []; dN = [];
            gap = str2double(app.Proj2DGapLab.Text);
            maxTheta = app.Proj2DThetaSlider.Limits(2);
            aThetaAll = 0:gap:app.Proj2DThetaSlider.Limits(2);
            for aTheta = maxTheta:-gap:0 
                [~,~,~,~,~,~,~,dValue,dCValue,dNValue] = ...
                  project2DEva_R(aTheta,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll);
                d(aTheta/gap+1) = dValue;
                dC(aTheta/gap+1) = dCValue;
                dN(aTheta/gap+1) = dNValue;
                if aTheta == maxTheta
                   dKey =  dValue;
                   dCKey =  dCValue;
                   dNKey =  dNValue;
                   PaTheta = aTheta;
                   PCaTheta = aTheta;
                   PNaTheta = aTheta;
                end
                
                if d(aTheta/gap+1) > dKey
                    dKey = d(aTheta/gap+1);
                    PaTheta = aTheta;
                end
                if dC(aTheta/gap+1) >= dCKey
                    dCKey = dC(aTheta/gap+1);
                    PCaTheta = aTheta;
                end
                if dN(aTheta/gap+1) >= dNKey
                    dNKey = dN(aTheta/gap+1);
                    PNaTheta = aTheta;
                end
            end
     
            app.Proj2DSR_Both.Value = 1;
            app.Proj2DThetaSlider.Value =  PaTheta;
            app.Proj2DThetaSliderLab.Text = num2str(PaTheta);
            app.Proj2DIC_FilteredEdge.Value = 1;
            plot(app.PredictThetaIm,aThetaAll,d);
            app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
            app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
            hold(app.PredictThetaIm)
            scatter(app.PredictThetaIm,PaTheta,dKey);
            hold(app.PredictThetaIm,'off')
            [BW,conProjR,conProj,X,Y,xNum,yNum,~,~,~] = ...
               project2DEva_R(app.Proj2DThetaSlider.Value,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll); 
            surf(app.Proj2DIm,X,Y,conProj)
            shading(app.Proj2DIm,'interp')
            app.Proj2DIm.XLim = [min(X(:)) max(X(:))];
            app.Proj2DIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.Proj2DIm,[xNum yNum 1])
            view(app.Proj2DIm,2)

        end

        % Selection changed function: Proj2DICChoice
        function Proj2DICChoiceSelectionChanged(app, event)
            global BW conProjR conProj X Y xNum yNum
            if app.Proj2DIC_Filtered.Value == 1 
               surf(app.Proj2DIm,X,Y,BW)
            elseif app.Proj2DIC_Raw.Value == 1 
               surf(app.Proj2DIm,X,Y,conProjR)
            elseif app.Proj2DIC_FilteredEdge.Value == 1 
               surf(app.Proj2DIm,X,Y,conProj)
            end
            shading(app.Proj2DIm,'interp')
            app.Proj2DIm.XLim = [min(X(:)) max(X(:))];
            app.Proj2DIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.Proj2DIm,[xNum yNum 1])
            view(app.Proj2DIm,2)    
        end

        % Value changed function: Proj2DThetaSlider
        function Proj2DThetaSliderValueChanged(app, event)
            global pAll pSol mean3DAll std3DAll BW conProjR conProj X Y xNum yNum aThetaAll d dC dN ProjResolution
            
            ProjResolution = app.Proj2DReso.Value;
            
            gap = str2double(app.Proj2DGapLab.Text);
            thetaSlider = gap*round(app.Proj2DThetaSlider.Value/gap);
            app.Proj2DThetaSliderLab.Text = num2str(thetaSlider);
            app.Proj2DThetaSlider.Value =  thetaSlider;
            app.Proj2DIC_FilteredEdge.Value = 1;
            
            if app.Proj2DSR_Both.Value == 1 
              plot(app.PredictThetaIm,aThetaAll,d);
              app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
              app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
              hold(app.PredictThetaIm,'on')
              scatter(app.PredictThetaIm,thetaSlider,d(thetaSlider/gap + 1));
              hold(app.PredictThetaIm,'off') 
            elseif app.Proj2DSR_Con.Value == 1 
              plot(app.PredictThetaIm,aThetaAll,dC);
              app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
              app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
              hold(app.PredictThetaIm,'on')
              scatter(app.PredictThetaIm,thetaSlider,dC(thetaSlider/gap + 1));
              hold(app.PredictThetaIm,'off') 
            elseif app.Proj2DSR_Num.Value == 1 
              plot(app.PredictThetaIm,aThetaAll,dN);
              app.PredictThetaIm.XLim = [min(aThetaAll(:)) max(aThetaAll(:))];
              app.PredictThetaIm.XTick = min(aThetaAll(:)): round((max(aThetaAll(:))-min(aThetaAll(:)))/4):max(aThetaAll(:));
              hold(app.PredictThetaIm,'on')
              scatter(app.PredictThetaIm,thetaSlider,dN(thetaSlider/gap + 1));
              hold(app.PredictThetaIm,'off') 
            end
            [BW,conProjR,conProj,X,Y,xNum,yNum,~,~,~] = ...
                project2DEva_R(thetaSlider,app.Proj2DReso.Value,str2double(app.Proj2DIFLab.Text),pAll,pSol,mean3DAll,std3DAll); 
            surf(app.Proj2DIm,X,Y,conProj)
            shading(app.Proj2DIm,'interp')
            app.Proj2DIm.XLim = [min(X(:)) max(X(:))];
            app.Proj2DIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.Proj2DIm,[xNum yNum 1])
            view(app.Proj2DIm,2) 
        end

        % Button pushed function: SubImStore
        function SubImStoreButtonPushed(app, event)
            global BW BWB BWTr pathPosName cnnName
            
            app.SubImSaveLight.Color = [0 1 0];
            app.SubImSaveLab.Text = 'running...';
            wb = waitbar(0,'performing calculation');
            close(wb);
            
            if app.ReducedAtom.Value == 1
               atomIon = 'atom';
            elseif app.ReducedIon.Value == 1
               atomIon = 'ion';
            end
            
            if ~exist(sprintf('%s%s',pathPosName,'\CNN'), 'dir')
                mkdir(sprintf('%s%s',pathPosName,'\CNN'))
            end
            
            cnnVersion = ['V' app.SubImVersion.Value];
            cnnName = [pathPosName 'CNN\' cnnVersion '_' atomIon '_' app.ReducedChem.Value '_' sprintf('%.2f',app.Proj2DReso.Value) '_' ...
                        app.Proj2DIonPercLab.Text '_' app.Proj2DIFLab.Text '_' int2str(app.Proj2DThetaSlider.Value) '_' app.SubImSize.Value '_'...
                        int2str(app.SubImGap.Value)];
            
            fileOut = collectImg_R(BW,str2double(app.SubImSize.Value),app.SubImGap.Value,[cnnName '.hdf5']);
            fileOut(strfind(fileOut,'\'))='/';
            fileOutStr = ['cnn\cnn.exe "' fileOut '"']    % Rhett changed here 20210608
            system(fileOutStr);
            
            app.Step4GBLinePanel.Visible = 1;
            
            app.GBLineIF.Enable = 0;
            app.GBLineMaxL.Enable = 0;
            app.GBLineSkel.Enable = 0;
            app.GBLineTr1.Enable = 0;
            app.GBLineTr2.Enable = 0;
            app.GBLineTr2.Enable = 0;
            app.GBLineMinL.Enable =0;
            app.GBLineStyle.Enable = 0;
            app.GBLineShow.Enable = 1;
            
            app.Step5GenerateGB.Visible = 0;
            app.FindGBPanel.Visible = 0;
            
           [BWB, BWTr] = chooseLabel_R(BW,str2double(app.SubImSize.Value),app.SubImGap.Value,[cnnName '.txt']);
           
           app.SubImSaveLight.Color = [0.65 0.65 0.65];
           app.SubImSaveLab.Text = 'idle...';
            
        end

        % Button pushed function: GenerateGBOBJ
        function GenerateGBOBJPushed(app, event)
            global vert2DRaw vert2D lineSeg cnnName
            
            aTheta = app.Proj2DThetaSlider.Value - 90;
            
            if app.GBLineShowLineSegRaw.Value == 1 && app.GBLineShowLineSegCorr.Value == 0
               obj = exclude3D_R(vert2DRaw,lineSeg,aTheta);
               triCorr = 'GB';
            else
               obj = exclude3D_R(vert2D,lineSeg,aTheta);
               triCorr = 'Tri';
            end
            
            lineStyle = ['S' app.GBLineStyle.Value];
            objFileName = [cnnName '__' lineStyle '_' app.GBLineIFLab.Text '_' app.GBLineSkelLab.Text '_' app.GBLineMaxLLab.Text '_' ...
                            app.GBLineMinLLab.Text '_' app.GBLineTr1Lab.Text '_' app.GBLineTr2Lab.Text '_' triCorr '.obj'];
            
            if ~exist('OutFileName','var')
               [outFileName, path] = uiputfile(objFileName,'Save *.obj file to');
               outFileName = [path outFileName];
            end
            
            writeObj_R(obj,outFileName);

        end

        % Callback function: GBLineIF, GBLineMaxL, GBLineMinL, 
        % GBLineShow, GBLineSkel, GBLineTr1, GBLineTr2
        function GBLineShowButtonPushed(app, event)
            global X Y xNum yNum BW BWB BWBN BWBNS BWBNSS BWTr BWTrF BWTrRegmax vert2DRaw vert2D lineSeg CCBTrCentroidRaw CCBTrCentroid...
                    ProjResolution conProjR
            
            minManual = 6.0;        
                    
            app.GBLineIF.Enable = 1;
            app.GBLineSkel.Enable = 1;
            
            app.GBLineTr1.Enable = 1;
            app.GBLineTr2.Enable = 1;
            app.GBLineShow.Enable = 1;
            
            app.GBLineMaxL.Enable = 1;
            app.GBLineMinL.Enable = 1;
            
            app.GBLineStyle.Enable = 1;
                     
            
            GBIF = str2double(app.GBLineIFLab.Text);
            GBSkel = str2double(app.GBLineSkelLab.Text);
            
            Tr1 = str2double(app.GBLineTr1Lab.Text);
            Tr2 = str2double(app.GBLineTr2Lab.Text);
            
            GBGap = app.SubImGap.Value;
                       
            maxL = str2double(app.GBLineMaxLLab.Text)/ProjResolution;
            minL = str2double(app.GBLineMinLLab.Text)/ProjResolution;
            
            if minL == 0 && maxL*ProjResolution == 1
              [BWBN,BWBNS,BWBNSS,BWTrF,BWTrRegmax,vert2DRaw,vert2D,lineSeg,CCBTrCentroidRaw,CCBTrCentroid,LabMin,LabMax,resetKey]...
                   = predictGB_R(GBIF,GBSkel,nan,nan,Tr1,Tr2,BWB,BWTr,X,Y,BW,GBGap); 
              if resetKey == 1
                app.GBLineIFLab.Text = "0";
                app.GBLineIF.Value = 0;
              end
              if ProjResolution*LabMin <= minManual
                LabMin = ceil(minManual/ProjResolution);
              end
              app.GBLineMaxL.Limits = [ProjResolution*round(LabMin),ProjResolution*round(LabMax)];
              app.GBLineMaxL.MajorTicks = [ProjResolution*round(LabMin), ProjResolution*(LabMin+(LabMax-LabMin)/3),ProjResolution*(LabMin+2*(LabMax-LabMin)/3),ProjResolution*round(LabMax)];
              app.GBLineMaxL.MajorTickLabels = {num2str(ProjResolution*round(LabMin)),'','',num2str(ProjResolution*round(LabMax))};
              app.GBLineMaxL.Value = ProjResolution*round(LabMax);
              app.GBLineMaxLLab.Text = num2str(ProjResolution*round(LabMax));
              
              app.GBLineMinL.Limits = [ProjResolution*round(LabMin),ProjResolution*round(LabMax)];
              app.GBLineMinL.MajorTicks = [ProjResolution*round(LabMin), ProjResolution*(LabMin+(LabMax-LabMin)/3),ProjResolution*(LabMin+2*(LabMax-LabMin)/3),ProjResolution*round(LabMax)];
              app.GBLineMinL.MajorTickLabels = {num2str(ProjResolution*round(LabMin)),'','',num2str(ProjResolution*round(LabMax))};
              app.GBLineMinL.Value = ProjResolution*round(LabMin);
              app.GBLineMinLLab.Text = num2str(ProjResolution*round(LabMin));
              if ProjResolution*LabMin <= minManual
                app.GBLineShowButtonPushed()
              end
              
            else
              [BWBN,BWBNS,BWBNSS,BWTrF,BWTrRegmax,vert2DRaw,vert2D,lineSeg,CCBTrCentroidRaw,CCBTrCentroid,LabMin,LabMax,resetKey]...
                   = predictGB_R(GBIF,GBSkel,minL,maxL,Tr1,Tr2,BWB,BWTr,X,Y,BW,GBGap); 
              if ~isempty(vert2D)
                  if resetKey == 1
                     app.GBLineIFLab.Text = "0";
                     app.GBLineIF.Value = 0;
                  end
                  if (app.GBLineMaxL.Limits(1) == ProjResolution*round(LabMin) || app.GBLineMaxL.Limits(1) == ceil(minManual/ProjResolution)) ...
                          && app.GBLineMaxL.Limits(2) == ProjResolution*round(LabMax)
                     app.GBLineMaxL.Value = ProjResolution*round(maxL);
                     app.GBLineMaxLLab.Text = num2str(ProjResolution*round(maxL));
                     app.GBLineMinL.Value = ProjResolution*round(minL);
                     app.GBLineMinLLab.Text = num2str(ProjResolution*round(minL));
                  else
                     if ProjResolution*LabMin <= minManual
                        LabMin = ceil(minManual/ProjResolution);
                     end
                     if minL < LabMin
                         minL = LabMin;
                     end
                     if maxL > LabMax
                         maxL = LabMax;
                     end
                     
                     if LabMin == LabMax
                        LabMin = ceil(minManual/ProjResolution);
                     end
                     app.GBLineMaxL.Limits = [ProjResolution*round(LabMin),ProjResolution*round(LabMax)];
                     app.GBLineMaxL.MajorTicks = [ProjResolution*round(LabMin), ProjResolution*(LabMin+(LabMax-LabMin)/3),ProjResolution*(LabMin+2*(LabMax-LabMin)/3),ProjResolution*round(LabMax)];
                     app.GBLineMaxL.MajorTickLabels = {num2str(ProjResolution*round(LabMin)),'','',num2str(ProjResolution*round(LabMax))};
                     app.GBLineMaxL.Value = ProjResolution*round(maxL);
                     app.GBLineMaxLLab.Text = num2str(ProjResolution*round(maxL));
                  
                     app.GBLineMinL.Limits = [ProjResolution*round(LabMin),ProjResolution*round(LabMax)];
                     app.GBLineMinL.MajorTicks = [ProjResolution*round(LabMin), ProjResolution*(LabMin+(LabMax-LabMin)/3),ProjResolution*(LabMin+2*(LabMax-LabMin)/3),ProjResolution*round(LabMax)];
                     app.GBLineMinL.MajorTickLabels = {num2str(ProjResolution*round(LabMin)),'','',num2str(ProjResolution*round(LabMax))};
                     app.GBLineMinL.Value = ProjResolution*round(minL);
                     app.GBLineMinLLab.Text = num2str(ProjResolution*round(minL));
                  end 
              else
                     app.GBLineIFLab.Text = "0";
                     app.GBLineIF.Value = 0;
                     app.GBLineMaxLLab.Text = "1";
                     app.GBLineMinLLab.Text = "0";
                     app.GBLineShowButtonPushed()
              end
              
            end
          
            if app.GBLineGBIC_Raw.Value == 1
                surf(app.GBLineGBIm,X,Y,BWB-max(BWB(:)))
            elseif app.GBLineGBIC_BW.Value == 1
                surf(app.GBLineGBIm,X,Y,BWBN-max(BWBN(:)))
            elseif app.GBLineGBIC_Skel.Value == 1
                surf(app.GBLineGBIm,X,Y,255*BWBNS)
            else 
                surf(app.GBLineGBIm,X,Y,255*BWBNSS)
            end
                
            shading(app.GBLineGBIm,'interp')
            app.GBLineGBIm.XLim = [min(X(:)) max(X(:))];
            app.GBLineGBIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.GBLineGBIm,[xNum+1 yNum+1 1])
            view(app.GBLineGBIm,2)
            hold(app.GBLineGBIm,'on')
            if app.GBLineShowLineSegCorr.Value == 1
                for i = 1:size(lineSeg,1)
                   xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                   plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
                end
            end
            hold(app.GBLineGBIm,'on')
            if app.GBLineShowLineSegRaw.Value == 1
                for i = 1:size(lineSeg,1)
                   xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                   plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
                end
            end
            hold(app.GBLineGBIm,'off')
            
            if app.GBLineTrIC_Raw.Value == 1
                surf(app.GBLineTrIm,X,Y,BWTr-max(BWTr(:)))
            elseif app.GBLineTrIC_Filtered.Value == 1
                surf(app.GBLineTrIm,X,Y,BWTrF-max(BWTrRegmax(:))-max(BWTrF(:)))
                hold(app.GBLineTrIm,'on')
                surf(app.GBLineTrIm,X,Y,BWTrRegmax-max(BWTrRegmax(:)))
            elseif app.GBLineTrIC_Con.Value == 1
                %surf(app.GBLineTrIm,X,Y,BW-max(BW(:)))                      % Rhett changed 20210601
                surf(app.GBLineTrIm,X,Y,conProjR-max(conProjR(:)))
            else
                surf(app.GBLineTrIm,X,Y,zeros(size(BW)))
            end
          
            shading(app.GBLineTrIm,'interp')
            app.GBLineTrIm.XLim = [min(X(:)) max(X(:))];
            app.GBLineTrIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.GBLineTrIm,[xNum+1 yNum+1 1])
            view(app.GBLineTrIm,2)
            hold(app.GBLineTrIm,'on')
            if app.GBLineShowLineSegCorr.Value == 1
                for i = 1:size(lineSeg,1)
                   xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                   plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
                end
            end
            hold(app.GBLineTrIm,'on')
            if app.GBLineShowLineSegRaw.Value == 1
                for i = 1:size(lineSeg,1)
                   xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                   plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
                end
            end
            hold(app.GBLineTrIm,'off')          
            
            app.FindGBPanel.Visible = 1;
            app.Step5GenerateGB.Visible = 1;                 
        end

        % Selection changed function: GBLineGBIC
        function GBLineGBICSelectionChanged(app, event)
            global X Y xNum yNum BWB BWBN BWBNS BWBNSS lineSeg CCBTrCentroidRaw CCBTrCentroid
            
            if app.GBLineGBIC_Raw.Value == 1
                surf(app.GBLineGBIm,X,Y,BWB-max(BWB(:)))
            elseif app.GBLineGBIC_BW.Value == 1
                surf(app.GBLineGBIm,X,Y,BWBN-max(BWBN(:)))
            elseif app.GBLineGBIC_Skel.Value == 1
                surf(app.GBLineGBIm,X,Y,255*BWBNS)
            else 
                surf(app.GBLineGBIm,X,Y,255*BWBNSS)
            end
            shading(app.GBLineGBIm,'interp')
            app.GBLineGBIm.XLim = [min(X(:)) max(X(:))];
            app.GBLineGBIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.GBLineGBIm,[xNum+1 yNum+1 1])
            view(app.GBLineGBIm,2)
            hold(app.GBLineGBIm,'off')
            
            if app.GBLineShowLineSegCorr.Value == 1
               hold(app.GBLineGBIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                  plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
               end
               hold(app.GBLineGBIm,'off')
            end
            
            if app.GBLineShowLineSegRaw.Value == 1
               hold(app.GBLineGBIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                  plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
               end
               hold(app.GBLineGBIm,'off')
            end            
        end

        % Selection changed function: GBLineTrIC
        function GBLineTrICSelectionChanged(app, event)
            global X Y xNum yNum BWTr BWTrF BWTrRegmax lineSeg CCBTrCentroidRaw CCBTrCentroid BW conProjR
            
            if app.GBLineTrIC_Raw.Value == 1
                surf(app.GBLineTrIm,X,Y,BWTr-max(BWTr(:)))
            elseif app.GBLineTrIC_Filtered.Value == 1
                surf(app.GBLineTrIm,X,Y,BWTrF-max(BWTrRegmax(:))-max(BWTrF(:)))
                hold(app.GBLineTrIm,'on')
                surf(app.GBLineTrIm,X,Y,BWTrRegmax-max(BWTrRegmax(:)))
            elseif app.GBLineTrIC_Con.Value == 1
               %surf(app.GBLineTrIm,X,Y,BW-max(BW(:)))
               surf(app.GBLineTrIm,X,Y,conProjR-max(conProjR(:)))
            else
               surf(app.GBLineTrIm,X,Y,zeros(size(BW)))
            end
            shading(app.GBLineTrIm,'interp')
            app.GBLineTrIm.XLim = [min(X(:)) max(X(:))];
            app.GBLineTrIm.YLim = [min(Y(:)) max(Y(:))];
            pbaspect(app.GBLineTrIm,[xNum+1 yNum+1 1])
            view(app.GBLineTrIm,2)
            hold(app.GBLineTrIm,'off')
            
            if app.GBLineShowLineSegCorr.Value == 1
               hold(app.GBLineTrIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                  plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
               end
               hold(app.GBLineTrIm,'off')
            end
            
            if app.GBLineShowLineSegRaw.Value == 1
               hold(app.GBLineTrIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                  plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
               end
               hold(app.GBLineTrIm,'off')
            end  
            
        end

        % Value changed function: GBLineShowLineSegCorr
        function GBLineShowLineSegCorrValueChanged(app, event)
            global X Y xNum yNum BW BWB BWBN BWBNS BWBNSS BWTr BWTrF BWTrRegmax lineSeg CCBTrCentroidRaw CCBTrCentroid conProjR
            if app.GBLineShowLineSegCorr.Value == 1
               hold(app.GBLineTrIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                  plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
               end
               hold(app.GBLineTrIm,'off')
                
               hold(app.GBLineGBIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                  plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
               end
               hold(app.GBLineGBIm,'off')
               
            else
               if app.GBLineGBIC_Raw.Value == 1
                   surf(app.GBLineGBIm,X,Y,BWB-max(BWB(:)));
               elseif app.GBLineGBIC_BW.Value == 1
                   surf(app.GBLineGBIm,X,Y,BWBN-1);
               elseif app.GBLineGBIC_Skel.Value == 1
                   surf(app.GBLineGBIm,X,Y,255*BWBNS);
               else
                   surf(app.GBLineGBIm,X,Y,255*BWBNSS);
               end
               shading(app.GBLineGBIm,'interp')
               app.GBLineGBIm.XLim = [min(X(:)) max(X(:))];
               app.GBLineGBIm.YLim = [min(Y(:)) max(Y(:))];
               pbaspect(app.GBLineGBIm,[xNum+1 yNum+1 1])
               view(app.GBLineGBIm,2)
               hold(app.GBLineGBIm,'off')
               
               if app.GBLineTrIC_Raw.Value == 1
                   surf(app.GBLineTrIm,X,Y,BWTr-max(BWTr(:)));
               elseif app.GBLineTrIC_Filtered.Value == 1
                   surf(app.GBLineTrIm,X,Y,BWTrF-max(BWTrF(:)))
                   hold(app.GBLineTrIm,'on')
                   surf(app.GBLineTrIm,X,Y,BWTrRegmax)
               elseif app.GBLineTrIC_Con.Value == 1
                   %surf(app.GBLineTrIm,X,Y,BW-max(BW(:)))
                   surf(app.GBLineTrIm,X,Y,conProjR-max(conProjR(:)))
               else
                   surf(app.GBLineTrIm,X,Y,zeros(size(BW)))
               end
               shading(app.GBLineTrIm,'interp')
               app.GBLineTrIm.XLim = [min(X(:)) max(X(:))];
               app.GBLineTrIm.YLim = [min(Y(:)) max(Y(:))];
               pbaspect(app.GBLineTrIm,[xNum+1 yNum+1 1])
               view(app.GBLineTrIm,2)
               hold(app.GBLineTrIm,'off')
                              
               if app.GBLineShowLineSegRaw.Value == 1
                 hold(app.GBLineGBIm,'on')
                 for i = 1:size(lineSeg,1)
                    xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                    plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
                 end
                 hold(app.GBLineGBIm,'off')
               
                 hold(app.GBLineTrIm,'on')
                 for i = 1:size(lineSeg,1)
                    xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                    plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
                 end
                 hold(app.GBLineTrIm,'off')
               end
            end
        end

        % Value changed function: GBLineShowLineSegRaw
        function GBLineShowLineSegRawValueChanged(app, event)
            global X Y xNum yNum BW BWB BWBN BWBNS BWBNSS BWTr BWTrF BWTrRegmax lineSeg CCBTrCentroidRaw CCBTrCentroid conProjR
            if app.GBLineShowLineSegRaw.Value == 1
               hold(app.GBLineGBIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                  plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
               end
               hold(app.GBLineGBIm,'off')
               
               hold(app.GBLineTrIm,'on')
               for i = 1:size(lineSeg,1)
                  xy = [CCBTrCentroidRaw(lineSeg(i,1),:); CCBTrCentroidRaw(lineSeg(i,2),:)];
                  plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','red');
               end
               hold(app.GBLineTrIm,'off')
            else
               if app.GBLineGBIC_Raw.Value == 1
                   surf(app.GBLineGBIm,X,Y,BWB-max(BWB(:)));
               elseif app.GBLineGBIC_BW.Value == 1
                   surf(app.GBLineGBIm,X,Y,BWBN-max(BWBN(:)));
               elseif app.GBLineGBIC_Skel.Value == 1
                   surf(app.GBLineGBIm,X,Y,255*BWBNS);
               else
                   surf(app.GBLineGBIm,X,Y,255*BWBNSS);
               end
               hold(app.GBLineGBIm,'off')
               shading(app.GBLineGBIm,'interp')
               app.GBLineGBIm.XLim = [min(X(:)) max(X(:))];
               app.GBLineGBIm.YLim = [min(Y(:)) max(Y(:))];
               pbaspect(app.GBLineGBIm,[xNum+1 yNum+1 1])
               view(app.GBLineGBIm,2)
               hold(app.GBLineGBIm,'off')
               
               if app.GBLineTrIC_Raw.Value == 1
                   surf(app.GBLineTrIm,X,Y,BWTr-max(BWTr(:)));
               elseif app.GBLineTrIC_Filtered.Value == 1
                   surf(app.GBLineTrIm,X,Y,BWTrF-max(BWTrF(:))-max(BWTrRegmax(:)))
                   hold(app.GBLineTrIm,'on')
                   surf(app.GBLineTrIm,X,Y,BWTrRegmax-max(BWTrRegmax(:)))
               elseif app.GBLineTrIC_Con.Value == 1
                   %surf(app.GBLineTrIm,X,Y,BW-max(BW(:)))
                   surf(app.GBLineTrIm,X,Y,conProjR-max(conProjR(:)))
               else
                   surf(app.GBLineTrIm,X,Y,zeros(size(BW)))
               end
               shading(app.GBLineTrIm,'interp')
               app.GBLineTrIm.XLim = [min(X(:)) max(X(:))];
               app.GBLineTrIm.YLim = [min(Y(:)) max(Y(:))];
               pbaspect(app.GBLineTrIm,[xNum+1 yNum+1 1])
               view(app.GBLineTrIm,2)
               hold(app.GBLineTrIm,'off')
                              
               if app.GBLineShowLineSegCorr.Value == 1
                 hold(app.GBLineGBIm,'on')
                 for i = 1:size(lineSeg,1)
                    xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                    plot(app.GBLineGBIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
                 end
                 hold(app.GBLineGBIm,'off')
               
                 hold(app.GBLineTrIm,'on')
                 for i = 1:size(lineSeg,1)
                    xy = [CCBTrCentroid(lineSeg(i,1),:); CCBTrCentroid(lineSeg(i,2),:)];
                    plot(app.GBLineTrIm,X(1,xy(:,2)),Y(xy(:,1),1),'LineWidth',2,'Color','green');
                 end
                 hold(app.GBLineTrIm,'off')
               end
            end
            
        end

        % Button pushed function: MapCal
        function MapCalButtonPushed(app, event)
            global pathPosName posName pathRngName rngName pathObjName objName foldName GBVal Fv SGBVal SFv DE allAtomsN allIonsN
           
            if strcmp(app.MapSysType.Value, '5000 XS')
                DE = 0.8;
            elseif strcmp(app.MapSysType.Value, '5000 XR/3000 XS/4000 XS')
                DE = 0.55;
            elseif strcmp(app.MapSysType.Value, '3000 HR/4000 HR')
                DE = 0.33;
            else
                DE = 1.0;
            end
                
            app.MapStatusLight.Color = [0 1 0];
            app.MapStatusLab.Text = 'running...';
            
            wb = waitbar(0,'performing calculation');
            close(wb);
            
            
            chemIn = app.MapChem.Value;
            mapZLim = str2double(app.MapZLimLab.Text);
            mapZWidth = str2double(app.MapZWidthLab.Text);
            
            if app.MapAtom.Value == 1
               allN = allAtomsN;
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               allN = allIonsN;
               atomIon = 'ion';
            end    
           
            [GBVal,Fv,SGBVal,SFv] = ...
                S3_runMeAfterBlender_R(chemIn,mapZLim,mapZWidth,pathPosName,posName,pathRngName,rngName,pathObjName,objName,DE,atomIon,allN);
           
           foldName = [objName(1:end-4) '_' chemIn '_' num2str(mapZLim) '_' num2str(mapZWidth) '_' atomIon];
           
           app.MapIC_Raw.Value = 1;
            
           yyaxis(app.AnalysisStatisIm,'left')
           errorbar(app.AnalysisStatisIm,GBVal(1,:)-0.1,GBVal(2,:),GBVal(3,:))
           xlabel(app.AnalysisStatisIm,'GB Number')
           ylabel(app.AnalysisStatisIm,['Interfacial excess(' atomIon 's/nm^{2})'])
           axis(app.AnalysisStatisIm,'auto')
           xlim(app.AnalysisStatisIm,[min(GBVal(1,:))-1,max(GBVal(1,:))+1])
           hold(app.AnalysisStatisIm,'off')
           yyaxis(app.AnalysisStatisIm,'right')
           errorbar(app.AnalysisStatisIm,GBVal(1,:)+0.1,GBVal(4,:),GBVal(5,:));
           ylabel(app.AnalysisStatisIm,['Solute concentration(' atomIon '%)'])
           axis(app.AnalysisStatisIm,'auto')
           xlim(app.AnalysisStatisIm,[min(GBVal(1,:))-1,max(GBVal(1,:))+1])
           hold(app.AnalysisStatisIm,'off')
           
           trisurf(Fv.faces,Fv.vertices(:,1),Fv.vertices(:,2),Fv.vertices(:,3),Fv.Cs,'Parent',app.OverCIm)
           axis(app.OverCIm, 'equal')
           rotate3d(app.OverCIm,'on');
           shading(app.OverCIm,'interp')
           c = colorbar(app.OverCIm,'southoutside');
           c.Label.String = ['Concentration (' atomIon '%)'];
           hold(app.OverCIm,'off')
           
           trisurf(Fv.faces,Fv.vertices(:,1),Fv.vertices(:,2),Fv.vertices(:,3),Fv.IEs,'Parent',app.OverIEIm)
           axis(app.OverIEIm, 'equal')
           rotate3d(app.OverIEIm,'on');
           shading(app.OverIEIm,'interp')
           c = colorbar(app.OverIEIm,'southoutside');
           c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
           hold(app.OverIEIm,'off')
          
           GBNumItems = "";
           for i = 1:size(GBVal,2)
                 GBNumItems(i) = num2str(round(GBVal(1,i)));
           end
            
           app.IndiGBNum.Items = GBNumItems;
           app.LadderGBNum.Items = GBNumItems;
           
           
           app.OverCDw.Value = min(Fv.Cs);
           app.OverCUp.Value = max(Fv.Cs);
           app.OverIEDw.Value = min(Fv.IEs);
           app.OverIEUp.Value = max(Fv.IEs);
           
           Cdw = app.OverCDw.Value;
           Cup = app.OverCUp.Value; 
           IEdw = app.OverIEDw.Value;
           IEup = app.OverIEUp.Value;
           app.OverCIm.CLim = [Cdw,Cup];
           app.OverIEIm.CLim = [IEdw,IEup];
           
           app.GBAnaPanel.Visible = 1;
           app.AnalysisStatisIm.Visible = 1;
           app.MapIC_Smoothed.Enable = 1;
           app.MapIC_Raw.Enable = 1;
           app.IndiCNorIm.Visible = 0;
           app.IndiIENorIm.Visible = 0;
           app.IndiCIm.Visible = 0;
           app.IndiIEIm.Visible = 0;
           app.IndiSyncIE.Visible = 0;
           app.IndiSyncC.Visible = 0;
           app.LadderIm.Visible = 0;
           app.IndiCDw.Editable = 0;
           app.IndiCUp.Editable = 0;
           app.IndiIEDw.Editable = 0;
           app.IndiIEUp.Editable = 0;
           
           %app.AnalysisNote1.Visible = 1;
           %app.AnalysisNote2.Visible = 1;
           
           app.MapStatusLight.Color = [0.65 0.65 0.65];
           app.MapStatusLab.Text = 'idle...';
           
           app.HisAllGBs.Value = 1;
           app.HisCBar.Value = 200;
           app.HisIEBar.Value = 200;
           
           Cs = Fv.Cs;
           IEs = Fv.IEs;
           
           app.HisArea.Value = 1;
           if app.HisArea.Value == 1
               Hweight = Fv.As;
               yLab = 'Area fraction';
               Ar = 'A';
           else
               Hweight = 1;
               yLab = 'Number fraction';
               Ar = 'N';
           end
           
           app.HisCDw.Value = min(Cs);
           app.HisCUp.Value = max(Cs);
           bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
           bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
           xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
           ylabel(app.HisCIm,yLab)
           axis(app.HisCIm,'auto')
           xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
           dataMatrix = [bin',histArea_R(Cs,bin,Hweight)];
           dataName = [pathPosName foldName '\histogram\Chist' Ar '.txt'];
           csvwrite(dataName,dataMatrix);
           
           app.HisIEDw.Value = min(IEs);
           app.HisIEUp.Value = max(IEs);
           bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
           bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
           xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
           ylabel(app.HisIEIm,yLab)
           axis(app.HisIEIm,'auto')
           xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
           dataMatrix = [bin',histArea_R(IEs,bin,Hweight)];
           dataName = [pathPosName foldName '\histogram\IEhist' Ar '.txt'];
           csvwrite(dataName,dataMatrix);
           
           Cs = SFv.Cs;
           IEs = SFv.IEs;
           
           bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
           dataMatrix = [bin',histArea_R(Cs,bin,Hweight)];
           dataName = [pathPosName foldName '\histogram\s_Chist' Ar '.txt'];
           csvwrite(dataName,dataMatrix);
           
           bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
           dataMatrix = [bin',histArea_R(IEs,bin,Hweight)];
           dataName = [pathPosName foldName '\histogram\s_IEhist' Ar '.txt'];
           csvwrite(dataName,dataMatrix);
           
           IECmat = [pathPosName foldName '\IEC.mat'];
           save(IECmat,'DE', 'allAtomsN', 'allIonsN', 'pathPosName', 'posName', 'pathRngName',...
               'rngName', 'pathObjName', 'objName', 'foldName', 'GBVal', 'Fv', 'SGBVal', 'SFv',...
               'chemIn', 'mapZLim', 'mapZWidth', 'atomIon', 'allN')   

        end

        % Value changed function: Proj2DIonPerc
        function Proj2DIonPercValueChanged(app, event)
            app.Proj2DIonPerc.Value = round(app.Proj2DIonPerc.Value);
            app.Proj2DIonPercLab.Text = num2str(app.Proj2DIonPerc.Value);
        end

        % Value changed function: Proj2DIF
        function Proj2DIFValueChanged(app, event)
            app.Proj2DIF.Value = round(50*app.Proj2DIF.Value)/50;
            app.Proj2DIFLab.Text = num2str(app.Proj2DIF.Value);  
        end

        % Value changed function: Proj2DGap
        function Proj2DGapValueChanged(app, event)
            aGap = round(app.Proj2DGap.Value);
            while (mod(180, aGap) ~= 0)
               aGap = aGap + 1;
            end            
            app.Proj2DGap.Value = aGap;
            app.Proj2DGapLab.Text = num2str(app.Proj2DGap.Value); 
        end

        % Value changed function: ReducedPerc
        function ReducedPercValueChanged(app, event)
            app.ReducedPerc.Value = round(app.ReducedPerc.Value);
            app.ReducedPercLab.Text = num2str(app.ReducedPerc.Value);      
        end

        % Value changed function: GBLineMaxL
        function GBLineMaxLValueChanged(app, event)
            global ProjResolution
            if app.GBLineMaxL.Value < app.GBLineMinL.Value
                app.GBLineMaxL.Value = app.GBLineMinL.Value;
            else
                app.GBLineMaxL.Value = ProjResolution*round(app.GBLineMaxL.Value/ProjResolution);
            end
            app.GBLineMaxLLab.Text = num2str(app.GBLineMaxL.Value);
        end

        % Value changed function: GBLineIF
        function GBLineIFValueChanged(app, event)
            app.GBLineIF.Value = round(50*app.GBLineIF.Value)/50;
            app.GBLineIFLab.Text = num2str(app.GBLineIF.Value); 
        end

        % Value changed function: GBLineSkel
        function GBLineSkelValueChanged(app, event)
            app.GBLineSkel.Value = round(app.GBLineSkel.Value);
            app.GBLineSkelLab.Text = num2str(app.GBLineSkel.Value); 
        end

        % Value changed function: GBLineTr1
        function GBLineTr1ValueChanged(app, event)
            app.GBLineTr1.Value = round(app.GBLineTr1.Value);
            app.GBLineTr1Lab.Text = num2str(app.GBLineTr1.Value);        
        end

        % Value changed function: GBLineTr2
        function GBLineTr2ValueChanged(app, event)
            app.GBLineTr2.Value =  round(app.GBLineTr2.Value);
            app.GBLineTr2Lab.Text = num2str(app.GBLineTr2.Value); 
        end

        % Value changed function: GBLineMinL
        function GBLineMinLValueChanged(app, event)
            global ProjResolution
            if app.GBLineMinL.Value > app.GBLineMaxL.Value
                app.GBLineMinL.Value = app.GBLineMaxL.Value;
            else
                app.GBLineMinL.Value = ProjResolution*round(app.GBLineMinL.Value/ProjResolution);
            end
            app.GBLineMinLLab.Text = num2str(app.GBLineMinL.Value);
        end

        % Value changed function: MapZLim
        function MapZLimValueChanged(app, event)
            app.MapZLim.Value =  round(app.MapZLim.Value);
            app.MapZLimLab.Text = num2str(app.MapZLim.Value);
        end

        % Value changed function: MapZWidth
        function MapZWidthValueChanged(app, event)
            app.MapZWidth.Value =  round(app.MapZWidth.Value);
            app.MapZWidthLab.Text = num2str(app.MapZWidth.Value);
        end

        % Selection changed function: MapIC
        function MapICSelectionChanged(app, event)
            global GBVal Fv SGBVal SFv
                    
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end
            
            app.HisAllGBs.Value = 1;
            app.HisCBar.Value = 200;
            app.HisIEBar.Value = 200;
            
            Hweight = Fv.As;
            
            if app.MapIC_Smoothed.Value == 1
                GB = SGBVal;
                faces = SFv.faces;
                vertices = SFv.vertices;
                Cs = SFv.Cs;
                IEs = SFv.IEs;     
            else
                GB = GBVal;
                faces = Fv.faces;
                vertices = Fv.vertices;
                Cs = Fv.Cs;
                IEs = Fv.IEs;
            end
            
            yyaxis(app.AnalysisStatisIm,'left')
            errorbar(app.AnalysisStatisIm,GB(1,:)-0.1,GB(2,:),GB(3,:))
            xlabel(app.AnalysisStatisIm,'GB Number')
            ylabel(app.AnalysisStatisIm,['Interfacial excess(' atomIon 's/nm^{2})'])
            axis(app.AnalysisStatisIm,'auto')
            xlim(app.AnalysisStatisIm,[min(GB(1,:))-1,max(GB(1,:))+1])
            hold(app.AnalysisStatisIm,'off')
            yyaxis(app.AnalysisStatisIm,'right')
            errorbar(app.AnalysisStatisIm,GB(1,:)+0.1,GB(4,:),GB(5,:));
            ylabel(app.AnalysisStatisIm,['Solute concentration(' atomIon '%)'])
            axis(app.AnalysisStatisIm,'auto')
            xlim(app.AnalysisStatisIm,[min(GB(1,:))-1,max(GB(1,:))+1])
            hold(app.AnalysisStatisIm,'off')
           
            trisurf(faces,vertices(:,1),vertices(:,2),vertices(:,3),Cs,'Parent',app.OverCIm)
            axis(app.OverCIm, 'equal')
            rotate3d(app.OverCIm,'on')
            shading(app.OverCIm,'interp')
            c = colorbar(app.OverCIm,'southoutside');
            c.Label.String = ['Concentration (' atomIon '%)'];
            hold(app.OverCIm,'off')
               
            trisurf(faces,vertices(:,1),vertices(:,2),vertices(:,3),IEs,'Parent',app.OverIEIm)
            axis(app.OverIEIm, 'equal')
            rotate3d(app.OverIEIm,'on')
            shading(app.OverIEIm,'interp')
            c = colorbar(app.OverIEIm,'southoutside');
            c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
            hold(app.OverIEIm,'off')
               
            app.OverCDw.Value = min(Cs);
            app.OverCUp.Value = max(Cs);
            app.OverIEDw.Value = min(IEs);
            app.OverIEUp.Value = max(IEs);
            
            Cdw = app.OverCDw.Value;
            Cup = app.OverCUp.Value; 
            IEdw = app.OverIEDw.Value;
            IEup = app.OverIEUp.Value;
            app.OverCIm.CLim = [Cdw,Cup];
            app.OverIEIm.CLim = [IEdw,IEup];
             
            if app.HisArea.Value == 1
               yLab = 'Area fraction';
            else
               Hweight = 1;
               yLab = 'Number fraction';
            end
           
            app.HisCDw.Value = min(Cs);
            app.HisCUp.Value = max(Cs);
            bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
            bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
            xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
            ylabel(app.HisCIm,yLab)
            axis(app.HisCIm,'auto')
            xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
           
            app.HisIEDw.Value = min(IEs);
            app.HisIEUp.Value = max(IEs);
            bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
            bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,yLab)
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])

        end

        % Button pushed function: IndiCal
        function IndiCalButtonPushed(app, event)
            global pathPosName foldName fv fvN v 

          
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end    
            
            if app.IndiIC_Smoothed.Value == 1 
                folderName = [pathPosName foldName '\s_maps'];  % add options
                smoothCheck = 's_';
            else
                folderName = [pathPosName foldName '\maps'];  % add options
                smoothCheck = '';
            end
            
            [fv,fvN,v] = plyreadM_R(app.IndiGBNum.Value,folderName);
            
            trisurf(fv.faces,fv.vertices(:,1),fv.vertices(:,2),fv.vertices(:,3),fv.Cs,'Parent',app.IndiCNorIm);
            axis(app.IndiCNorIm, 'equal')
            rotate3d(app.IndiCNorIm,'off')
            shading(app.IndiCNorIm,'interp')
            view(app.IndiCNorIm,v)
            c = colorbar(app.IndiCNorIm,'southoutside');
            c.Label.String = ['Concentration (' atomIon '%)'];
            hold(app.IndiCNorIm,'off')

            trisurf(fvN.faces,fvN.vertices(:,1),fvN.vertices(:,2),fvN.Cs,'Parent',app.IndiCIm);
            axis(app.IndiCIm, 'equal')
            rotate3d(app.IndiCIm,'on')
            shading(app.IndiCIm,'interp')
            view(app.IndiCIm,[57 26])
            c = colorbar(app.IndiCIm,'southoutside');
            c.Label.String = ['Concentration (' atomIon '%)'];
            hold(app.IndiCIm,'off')
       
            trisurf(fv.faces,fv.vertices(:,1),fv.vertices(:,2),fv.vertices(:,3),fv.IEs,'Parent',app.IndiIENorIm);
            axis(app.IndiIENorIm, 'equal')
            rotate3d(app.IndiIENorIm,'off')
            shading(app.IndiIENorIm,'interp')
            view(app.IndiIENorIm,v)
            c = colorbar(app.IndiIENorIm,'southoutside');
            c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
            hold(app.IndiIENorIm,'off')

            trisurf(fvN.faces,fvN.vertices(:,1),fvN.vertices(:,2),fvN.IEs,'Parent',app.IndiIEIm);
            axis(app.IndiIEIm, 'equal')
            rotate3d(app.IndiIEIm,'on')
            shading(app.IndiIEIm,'interp')
            view(app.IndiIEIm,[57 26])
            c = colorbar(app.IndiIEIm,'southoutside');
            c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
            hold(app.IndiIEIm,'off')
            
            app.IndiCDw.Value = min(fv.Cs);
            app.IndiCUp.Value = max(fv.Cs);
            app.IndiIEDw.Value = min(fv.IEs);
            app.IndiIEUp.Value = max(fv.IEs);
            
            app.IndiCNorIm.CLim = [app.IndiCDw.Value,app.IndiCUp.Value];
            app.IndiIENorIm.CLim = [app.IndiIEDw.Value,app.IndiIEUp.Value];
            
            app.IndiCIm.CLim = [app.IndiCDw.Value,app.IndiCUp.Value];
            app.IndiIEIm.CLim = [app.IndiIEDw.Value,app.IndiIEUp.Value];
            
            app.IndiCNorIm.Visible = 1;
            app.IndiIENorIm.Visible = 1;
            app.IndiCIm.Visible = 1;
            app.IndiIEIm.Visible = 1;
            app.IndiSyncIE.Visible = 1;
            app.IndiSyncC.Visible = 1;
            app.IndiCDw.Editable = 1;
            app.IndiCUp.Editable = 1;
            app.IndiIEDw.Editable = 1;
            app.IndiIEUp.Editable = 1;
                    
            app.HisCBar.Value = 40;
            app.HisIEBar.Value = 40;
            
            Cs = fv.Cs;
            IEs = fv.IEs;
            Hweight = fv.As;
            
            if app.HisArea.Value == 1
               yLab = 'Area fraction';
               Ar = 'A';
            else
               Hweight = 1;
               yLab = 'Number fraction';
               Ar = 'N';
            end
           
            app.HisCDw.Value = min(Cs);
            app.HisCUp.Value = max(Cs);
            bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
            bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
            xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
            ylabel(app.HisCIm,yLab)
            axis(app.HisCIm,'auto')
            xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
            dataMatrix = [bin',histArea_R(Cs,bin,Hweight)];
            dataName = [pathPosName foldName '\histogram\' smoothCheck app.IndiGBNum.Value '_Chist' Ar '.txt'];
            csvwrite(dataName,dataMatrix);
           
            app.HisIEDw.Value = min(IEs);
            app.HisIEUp.Value = max(IEs);
            bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
            bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,yLab)
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            dataMatrix =  [bin',histArea_R(IEs,bin,Hweight)];
            dataName = [pathPosName foldName '\histogram\' smoothCheck app.IndiGBNum.Value '_IEhist' Ar '.txt'];
            csvwrite(dataName,dataMatrix);
            
            app.HisIndiGBs.Visible = 1;
            app.HisIndiGBs.Value = 1;
            
            
        end

        % Callback function: LadderGBNum, LadderPlot
        function LadderPlotButtonPushed(app, event)
            global pathPosName foldName
            
            app.LadderIm.Visible = 1;
            
            folderName = [pathPosName foldName '\ladder'];  % add options
            
            ladderGBNum = str2double(app.LadderGBNum.Value);
            
            if ladderGBNum > 9
               ladderGBNumT = num2str(ladderGBNum);
            else
               ladderGBNumT = ['0' num2str(ladderGBNum)];
            end
            
            ladderName = [folderName '\Ladder plots No.' ladderGBNumT '.jpg'];
            ladder= imread(ladderName);
            ladder = imresize(ladder,2);
            imshow(ladder,'Parent',app.LadderIm);
            
        end

        % Selection changed function: IndiIC
        function IndiICSelectionChanged(app, event)
            global pathPosName foldName fv fvN v
                        
          
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end 
            
            if app.IndiIC_Smoothed.Value == 1 
                folderName = [pathPosName foldName '\s_maps'];  % add options
                smoothCheck = 's_';
            else
                folderName = [pathPosName foldName '\maps'];  % add options
                smoothCheck = '';
            end
            
            [fv,fvN,v] = plyreadM_R(app.IndiGBNum.Value,folderName);
            
            trisurf(fv.faces,fv.vertices(:,1),fv.vertices(:,2),fv.vertices(:,3),fv.Cs,'Parent',app.IndiCNorIm);
            axis(app.IndiCNorIm, 'equal')
            rotate3d(app.IndiCNorIm,'on')
            shading(app.IndiCNorIm,'interp')
            view(app.IndiCNorIm,v)
            c = colorbar(app.IndiCNorIm,'southoutside');
            c.Label.String = ['Concentration (' atomIon '%)'];
            hold(app.IndiCNorIm,'off')

            trisurf(fvN.faces,fvN.vertices(:,1),fvN.vertices(:,2),fvN.Cs,'Parent',app.IndiCIm);
            axis(app.IndiCIm, 'equal')
            rotate3d(app.IndiCIm,'on')
            shading(app.IndiCIm,'interp')
            view(app.IndiCIm,[57 26])
            c = colorbar(app.IndiCIm,'southoutside');
            c.Label.String = ['Concentration (' atomIon '%)'];
            hold(app.IndiCIm,'off')
       
            trisurf(fv.faces,fv.vertices(:,1),fv.vertices(:,2),fv.vertices(:,3),fv.IEs,'Parent',app.IndiIENorIm);
            axis(app.IndiIENorIm, 'equal')
            rotate3d(app.IndiIENorIm,'on')
            shading(app.IndiIENorIm,'interp')
            view(app.IndiIENorIm,v)
            c = colorbar(app.IndiIENorIm,'southoutside');
            c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
            hold(app.IndiIENorIm,'off')

            trisurf(fvN.faces,fvN.vertices(:,1),fvN.vertices(:,2),fvN.IEs,'Parent',app.IndiIEIm);
            axis(app.IndiIEIm, 'equal')
            rotate3d(app.IndiIEIm,'on')
            shading(app.IndiIEIm,'interp')
            view(app.IndiIEIm,[57 26])
            c = colorbar(app.IndiIEIm,'southoutside');
            c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
            hold(app.IndiIEIm,'off')
            
            app.IndiCDw.Value = min(fv.Cs);
            app.IndiCUp.Value = max(fv.Cs);
            app.IndiIEDw.Value = min(fv.IEs);
            app.IndiIEUp.Value = max(fv.IEs);
            
            app.IndiCNorIm.CLim = [app.IndiCDw.Value,app.IndiCUp.Value];
            app.IndiIENorIm.CLim = [app.IndiIEDw.Value,app.IndiIEUp.Value];
            
            app.IndiCIm.CLim = [app.IndiCDw.Value,app.IndiCUp.Value];
            app.IndiIEIm.CLim = [app.IndiIEDw.Value,app.IndiIEUp.Value];
            
            app.IndiCNorIm.Visible = 1;
            app.IndiIENorIm.Visible = 1;
            app.IndiCIm.Visible = 1;
            app.IndiIEIm.Visible = 1;
            app.IndiSyncIE.Visible = 1;
            app.IndiSyncC.Visible = 1;
            app.IndiCDw.Editable = 1;
            app.IndiCUp.Editable = 1;
            app.IndiIEDw.Editable = 1;
            app.IndiIEUp.Editable = 1;
            
            app.HisCBar.Value = 40;
            app.HisIEBar.Value = 40;
            
            Cs = fv.Cs;
            IEs = fv.IEs;
            Hweight = fv.As;
            
            if app.HisArea.Value == 1
               yLab = 'Area fraction';
               Ar = 'A';
            else
               Hweight = 1;
               yLab = 'Number fraction';
               Ar = 'N';
            end
           
            app.HisCDw.Value = min(Cs);
            app.HisCUp.Value = max(Cs);
            bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
            bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
            xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
            ylabel(app.HisCIm,yLab)
            axis(app.HisCIm,'auto')
            xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
            dataMatrix = [bin',histArea_R(Cs,bin,Hweight)];
            dataName = [pathPosName foldName '\histogram\' smoothCheck app.IndiGBNum.Value '_Chist' Ar '.txt'];
            csvwrite(dataName,dataMatrix);
            
            app.HisIEDw.Value = min(IEs);
            app.HisIEUp.Value = max(IEs);
            bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
            bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,yLab)
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            dataMatrix =  [bin',histArea_R(IEs,bin,Hweight)];
            dataName = [pathPosName foldName '\histogram\' smoothCheck app.IndiGBNum.Value '_IEhist' Ar '.txt'];
            csvwrite(dataName,dataMatrix);
            
            app.HisIndiGBs.Visible = 1;
            app.HisIndiGBs.Value = 1;
            
            app.LadderZoom.Value = 'On';
            zoom(app.LadderIm,'on');

        end

        % Value changed function: LadderZoom
        function LadderZoomValueChanged(app, event)
         
            if strcmp(app.LadderZoom.Value,'On')
                zoom(app.LadderIm,'on');
            else
                zoom(app.LadderIm,'off');
                axis(app.LadderIm,'tight');
            end
            
        end

        % Callback function
        function OverCEditValueChanged(app, event)
            if strcmp(app.OverCEdit.Value, 'Rot3D')  
                plotedit(app.OverCIm,'off')
                rotate3d(app.OverCIm,'on')
            else
                rotate3d(app.OverCIm,'off')
                plotedit(app.OverCIm,'on')
            end  
        end

        % Callback function
        function OverIEEditValueChanged(app, event)
           if strcmp(app.OverCEdit.Value, 'Rot3D')  
                rotate3d(app.OverIEIm,'on')
            else
                plotedit(app.OverIEIm,'on')
            end  
        end

        % Callback function
        function OverCColorButtonPushed(app, event)
            colormapeditor(app.OverCIm);
        end

        % Callback function
        function OverIEColorButtonPushed(app, event)
            colormapeditor(app.OverIEIm);
        end

        % Value changed function: OverCDw
        function OverCDwValueChanged(app, event)
            dw = app.OverCDw.Value;
            up = app.OverCUp.Value;
            app.OverCIm.CLim = [dw,up];
        end

        % Value changed function: OverCUp
        function OverCUpValueChanged(app, event)
            dw = app.OverCDw.Value;
            up = app.OverCUp.Value;
            app.OverCIm.CLim = [dw,up];      
        end

        % Value changed function: OverIEDw
        function OverIEDwValueChanged(app, event)
            dw = app.OverIEDw.Value;
            up = app.OverIEUp.Value;
            app.OverIEIm.CLim = [dw,up];       
        end

        % Value changed function: OverIEUp
        function OverIEUpValueChanged(app, event)
            dw = app.OverIEDw.Value;
            up = app.OverIEUp.Value;
            app.OverIEIm.CLim = [dw,up];
        end

        % Button pushed function: OverCSave
        function OverCSaveButtonPushed(app, event)
            global pathPosName foldName Fv SFv
                      
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end 
            
            if app.MapIC_Smoothed.Value == 1
               plyName = [pathPosName foldName '\overview\s_CMap.ply'];
               map = colormap(app.OverCIm);
               lim = caxis(app.OverCIm);
               vals = SFv.Cs; 
               vals(vals > lim(2)) = lim(2);
               vals(vals < lim(1)) = lim(1);
               vertColors = value2VertColor(vals,map,lim);
               comment = ['comment ' num2str(min(vals),2) ' to ' num2str(max(vals)) ' ' atomIon ' percentage']; 
               patch2ply(SFv,vertColors,plyName,comment);
            else
               plyName = [pathPosName foldName '\overview\CMap.ply'];
               map = colormap(app.OverCIm);
               lim = caxis(app.OverCIm);
               vals = Fv.Cs; 
               vals(vals > lim(2)) = lim(2);
               vals(vals < lim(1)) = lim(1);
               vertColors = value2VertColor(vals,map,lim);
               comment = ['comment ' num2str(min(vals),2) ' to ' num2str(max(vals)) ' ' atomIon ' percentage']; 
               patch2ply(Fv,vertColors,plyName,comment); 
            end
        end

        % Button pushed function: OverIESave
        function OverIESaveButtonPushed(app, event)
            global pathPosName foldName Fv SFv
                      
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end 
            
            if app.MapIC_Smoothed.Value == 1
               plyName = [pathPosName foldName '\overview\s_IEMap.ply'];
               map = colormap(app.OverIEIm);
               lim = caxis(app.OverIEIm);
               vals = SFv.IEs; 
               vals(vals > lim(2)) = lim(2);
               vals(vals < lim(1)) = lim(1);
               vertColors = value2VertColor(vals,map,lim);
               comment = ['comment ' num2str(min(vals),2) ' to ' num2str(max(vals)) ' ' atomIon 's/nm2']; 
               patch2ply(SFv,vertColors,plyName,comment);
            else
               plyName = [pathPosName foldName '\overview\IEMap.ply'];
               map = colormap(app.OverIEIm);
               lim = caxis(app.OverIEIm);
               vals = Fv.IEs; 
               vals(vals > lim(2)) = lim(2);
               vals(vals < lim(1)) = lim(1);
               vertColors = value2VertColor(vals,map,lim);
               comment = ['comment ' num2str(min(vals),2) ' to ' num2str(max(vals)) ' ' atomIon 's/nm2']; 
               patch2ply(Fv,vertColors,plyName,comment); 
            end
        end

        % Value changed function: IndiCDw
        function IndiCDwValueChanged(app, event)
            dw = app.IndiCDw.Value;
            up = app.IndiCUp.Value;
            app.IndiCNorIm.CLim = [dw,up];
            app.IndiCIm.CLim = [dw,up]; 
        end

        % Value changed function: IndiCUp
        function IndiCUpValueChanged(app, event)
            dw = app.IndiCDw.Value;
            up = app.IndiCUp.Value;
            app.IndiCNorIm.CLim = [dw,up];
            app.IndiCIm.CLim = [dw,up]; 
        end

        % Value changed function: IndiIEDw
        function IndiIEDwValueChanged(app, event)
            dw = app.IndiIEDw.Value;
            up = app.IndiIEUp.Value;
            app.IndiIENorIm.CLim = [dw,up];
            app.IndiIEIm.CLim = [dw,up];  
        end

        % Value changed function: IndiIEUp
        function IndiIEUpValueChanged(app, event)
            dw = app.IndiIEDw.Value;
            up = app.IndiIEUp.Value;
            app.IndiIENorIm.CLim = [dw,up];
            app.IndiIEIm.CLim = [dw,up];    
        end

        % Button pushed function: Training
        function TrainingPushed(app, event)
            numOfGBnetRestar = app.TrainingNumOfGBnetRestar.Value;
            numOfGBnet = app.TrainingNumOfGBnet.Value;
            segment = app.TrainingSegment.Value;
            
            S0_runMe_preTrain_R(numOfGBnetRestar,numOfGBnet,segment)
        end

        % Button pushed function: MapObjFile
        function MapObjFileButtonPushed(app, event)
            global pathObjName objName pathPosName
            
            app.MapCal.Enable = 1;
            
            [objName,pathObjName] = uigetfile('*.obj','Select a file',pathPosName);
        end

        % Value changed function: TrainingNumOfGBnetRestar
        function TrainingNumOfGBnetRestarValueChanged(app, event)
            restar = round(app.TrainingNumOfGBnetRestar.Value);
            app.TrainingNumOfGBnetRestar.Value = restar;
            numGBnet = app.TrainingNumOfGBnet.Value;
            segment = {};
            segmentN = [];
            if restar > numGBnet
               app.TrainingNumOfGBnet.Value = restar;
               numGBnet = restar;
               for i = 1:numGBnet-1
                   if mod(numGBnet,i) == 0
                       segment{end+1} = num2str(i);
                       segmentN(end+1) = numGBnet/i;
                   end
               end
               app.TrainingSegment.Items = segment;
               app.TrainingSegment.ItemsData = segmentN;
            end 
        end

        % Value changed function: TrainingNumOfGBnet
        function TrainingNumOfGBnetValueChanged(app, event)
            numGBnet = round(app.TrainingNumOfGBnet.Value);
            app.TrainingNumOfGBnet.Value = numGBnet;
            segment = {};
            segmentN = [];
            for i = 1:numGBnet-1
               if mod(numGBnet,i) == 0
                 segment{end+1} = num2str(i);
                 segmentN(end+1) = numGBnet/i;
               end
            end
            app.TrainingSegment.Items = segment;
            app.TrainingSegment.ItemsData = segmentN;
            
            restar = app.TrainingNumOfGBnetRestar.Value;  
            
            if restar > numGBnet
               app.TrainingNumOfGBnetRestar.Value = numGBnet;
            end 
        end

        % Selection changed function: ReducedAtomIon
        function ReducedAtomIonSelectionChanged(app, event)
            global allAtomsN allIonsN
            if app.ReducedAtom.Value == 1
               app.ReducedChem.Items = allAtomsN;
               app.MapAtom.Value = 1;
               app.MapChem.Items = allAtomsN;
            elseif app.ReducedIon.Value == 1
               app.ReducedChem.Items = allIonsN;
               app.MapAtom.Value = 1;
               app.MapChem.Items = allIonsN;
            end
            
        end

        % Selection changed function: MapAtomIon
        function MapAtomIonSelectionChanged(app, event)
            global allAtomsN allIonsN

            if app.MapAtom.Value == 1
               app.MapChem.Items = allAtomsN;
            elseif app.MapIon.Value == 1
               app.MapChem.Items = allIonsN;
            end
            
        end

        % Button pushed function: IndiSave
        function IndiSaveButtonPushed(app, event)
            global pathPosName foldName fv
            
          
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end
            
            if app.IndiIC_Smoothed.Value == 1 
                folderName = [pathPosName foldName '\s_maps'];  % add options
            else
                folderName = [pathPosName foldName '\maps'];  % add options
            end
            
            str = regexprep(app.IndiGBNum.Value, ' ', '_');
            plyName = [folderName '\' str '_IEMap.ply'];
            map = colormap(app.IndiCIm);
            lim = caxis(app.IndiCIm);
            vals = fv.IEs; 
            vals(vals > lim(2)) = lim(2);
            vals(vals < lim(1)) = lim(1);
            vertColors = value2VertColor(vals,map,lim);
            comment = ['comment ' num2str(min(vals),2) ' to ' num2str(max(vals)) ' ' atomIon 's/nm2']; 
            patch2ply(fv,vertColors,plyName,comment);
            
            plyName = [folderName '\' str '_CMap.ply'];
            map = colormap(app.IndiIEIm);
            lim = caxis(app.IndiIEIm);
            vals = fv.Cs; 
            vals(vals > lim(2)) = lim(2);
            vals(vals < lim(1)) = lim(1);
            vertColors = value2VertColor(vals,map,lim);
            comment = ['comment ' num2str(min(vals),2) ' to ' num2str(max(vals)) ' ' atomIon ' percentage']; 
            patch2ply(fv,vertColors,plyName,comment);
            
        end

        % Callback function
        function HisCalButtonPushed(app, event)
           
              
        end

        % Button pushed function: HisSave
        function HisSaveButtonPushed(app, event)
           global pathPosName foldName Fv SFv fv
           
           if app.HisAllGBs.Value == 1
               GBNumValue = '';
               Hweight = Fv.As;
               if app.MapIC_Smoothed.Value == 1
                   smoothCheck = 's_';
                   Cs = SFv.Cs;
                   IEs = SFv.IEs;
               else
                   Cs = Fv.Cs;
                   IEs = Fv.IEs;
                   smoothCheck = '';
               end
            else
               GBNumValue = [app.IndiGBNum.Value '_'];
               if app.IndiIC_Smoothed.Value == 1 
                   folderName = [pathPosName foldName '\s_maps'];  % add options
                   smoothCheck = 's_';
               else
                   folderName = [pathPosName foldName '\maps'];  % add options
                   smoothCheck = '';
               end
               [fv,~,~] = plyreadM_R(app.IndiGBNum.Value,folderName);
               Cs = fv.Cs;
               IEs = fv.IEs;
               Hweight = fv.As;
            end
            
            if app.HisArea.Value == 1
               Ar = 'A';
            else
               Ar = 'N'; 
               Hweight = 1;
            end
            
            bin = app.HisCDw.Value:(app.HisCUp.Value-app.HisCDw.Value)/app.HisCBar.Value:app.HisCUp.Value;
            dataMatrix = [bin',histArea_R(Cs,bin,Hweight)];
            dataName = [pathPosName foldName '\histogram\' smoothCheck GBNumValue 'Chist_' num2str(app.HisCDw.Value) '_' num2str(app.HisCUp.Value) '_' num2str(app.HisCBar.Value) Ar '.txt'];
            csvwrite(dataName,dataMatrix);
            
            bin = app.HisIEDw.Value:(app.HisIEUp.Value-app.HisIEDw.Value)/app.HisIEBar.Value:app.HisIEUp.Value;
            dataMatrix = [bin',histArea_R(IEs,bin,Hweight)];
            dataName = [pathPosName foldName '\histogram\' smoothCheck GBNumValue 'IEhist_' num2str(app.HisIEDw.Value) '_' num2str(app.HisIEUp.Value) '_' num2str(app.HisCBar.Value) Ar '.txt'];
            csvwrite(dataName,dataMatrix);
               
    
        end

        % Value changed function: HisCBar, HisCDw, HisCUp
        function HisCBarValueChanged(app, event)
            global fv Fv SFv
            
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end
            
            if app.HisAllGBs.Value == 1
               Hweight = Fv.As;
               if app.MapIC_Smoothed.Value == 1
                  Cs = SFv.Cs;
               else
                  Cs = Fv.Cs;
               end
            else
               Cs = fv.Cs;
               Hweight = fv.As;
            end
            
            if app.HisArea.Value == 1
               yLab = 'Area fraction';
            else
               Hweight = 1;
               yLab = 'Number fraction';
            end
            
            bin = app.HisCDw.Value:(app.HisCUp.Value-app.HisCDw.Value)/app.HisCBar.Value:app.HisCUp.Value;
            bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
            xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
            ylabel(app.HisCIm,yLab)
            axis(app.HisCIm,'auto')
            xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
            
        end

        % Callback function
        function HisCDwValueChanged(app, event)
            
            
        end

        % Callback function
        function HisCUpValueChanged(app, event)
            
            
        end

        % Value changed function: HisIEBar, HisIEDw, HisIEUp
        function HisIEBarValueChanged(app, event)
            global fv Fv SFv
            
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end
            
            if app.HisAllGBs.Value == 1
               Hweight = Fv.As;
               if app.MapIC_Smoothed.Value == 1
                  IEs = SFv.IEs;
               else
                  IEs = Fv.IEs;
               end
            else
               IEs = fv.IEs;
               Hweight = fv.As;
            end
            
            if app.HisArea.Value == 1
               yLab = 'Area fraction';
            else
               Hweight = 1;
               yLab = 'Number fraction';
            end
                        
            bin = app.HisIEDw.Value:(app.HisIEUp.Value-app.HisIEDw.Value)/app.HisIEBar.Value:app.HisIEUp.Value;
            bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,yLab)
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            
        end

        % Callback function
        function HisIEDwValueChanged(app, event)
            global fv Fv SFv
            
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end
            
            bin = app.HisIEDw.Value:(app.HisIEUp.Value-app.HisIEDw.Value)/app.HisIEBar.Value:app.HisIEUp.Value;
            if app.HisAllGBs.Value == 1
               if app.MapIC_Smoothed.Value == 1
                   bar(app.HisIEIm,bin,histc(SFv.IEs,bin),'histc');
               else
                   bar(app.HisIEIm,bin,histc(Fv.IEs,bin),'histc');
               end
            else
                bar(app.HisIEIm,bin,histc(fv.IEs,bin),'histc');
            end
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,'Count')
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            
        end

        % Callback function
        function HisIEUpValueChanged(app, event)
            global fv Fv SFv
            
            if app.MapAtom.Value == 1
               atomIon = 'atom';
            elseif app.MapIon.Value == 1
               atomIon = 'ion';
            end
            
            bin = app.HisIEDw.Value:(app.HisIEUp.Value-app.HisIEDw.Value)/app.HisIEBar.Value:app.HisIEUp.Value;
            if app.HisAllGBs.Value == 1
               if app.MapIC_Smoothed.Value == 1
                   bar(app.HisIEIm,bin,histc(SFv.IEs,bin),'histc');
               else
                   bar(app.HisIEIm,bin,histc(Fv.IEs,bin),'histc');
               end
            else
                bar(app.HisIEIm,bin,histc(fv.IEs,bin),'histc');
            end
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,'Count')
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            
        end

        % Selection changed function: HisMethod, HisType
        function HisTypeSelectionChanged(app, event)
            global Fv SFv fv pathPosName foldName
            if app.MapAtom.Value == 1
                    atomIon = 'atom';
            elseif app.MapIon.Value == 1
                    atomIon = 'ion';
            end
            
            if app.HisAllGBs.Value == 1
                app.HisCBar.Value = 200;
                app.HisIEBar.Value = 200;
                Hweight = Fv.As;
                if app.MapIC_Smoothed.Value == 1
                    Cs = SFv.Cs;
                    IEs = SFv.IEs;
                else
                    Cs = Fv.Cs;
                    IEs = Fv.IEs;
                end
            else
                if app.IndiIC_Smoothed.Value == 1 
                   folderName = [pathPosName foldName '\s_maps'];  % add options
                else
                   folderName = [pathPosName foldName '\maps'];  % add options
                end
                [fv,~,~] = plyreadM_R(app.IndiGBNum.Value,folderName);
                app.HisCBar.Value = 40;
                app.HisIEBar.Value = 40;
                Cs = fv.Cs;
                IEs = fv.IEs;
                Hweight = fv.As;
            end
            
            if app.HisArea.Value == 1
               yLab = 'Area fraction';
            else
               Hweight = 1;
               yLab = 'Number fraction';
            end
           
            app.HisCDw.Value = min(Cs);
            app.HisCUp.Value = max(Cs);
            bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
            bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
            xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
            ylabel(app.HisCIm,yLab)
            axis(app.HisCIm,'auto')
            xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
           
            app.HisIEDw.Value = min(IEs);
            app.HisIEUp.Value = max(IEs);
            bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
            bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
            xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
            ylabel(app.HisIEIm,yLab)
            axis(app.HisIEIm,'auto')
            xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            
        end

        % Button pushed function: MapLoad
        function MapLoadPushed(app, event)
            global pathPosName posName pathRngName rngName pathObjName objName foldName GBVal Fv SGBVal SFv DE allAtomsN allIonsN
            
            app.MapCal.Enable = 1;
            
            app.MapSysType.Enable = 1;
            app.MapAtom.Enable = 1;
            app.MapIon.Enable = 1;
            app.MapChem.Enable = 1;
            app.MapZLim.Enable = 1;
            app.MapZWidth.Enable = 1;
            app.MapObjFile.Enable = 1;
            
            
            [matName,pathMatName] = uigetfile('*.mat','Select a file',pathPosName);
            
            load([pathMatName '\' matName])

            pathPosName = pathMatName(1:end-length(foldName)-1);
            
            pathRngName = pathPosName;
            pathObjName = [pathPosName '\CNN\'];
            
            if DE == 0.8
                app.MapSysType.Value = '5000 XS';
            elseif DE == 0.55 
                app.MapSysType.Value = '5000 XR/3000 XS/4000 XS';
            elseif DE == 0.33
                app.MapSysType.Value = '3000 HR/4000 HR';
            else
                app.MapSysType.Value = 'Synthetic data';
            end
            
            app.MapChem.Value = chemIn;
            app.MapZLimLab.Text = num2str(mapZLim);
            app.MapZLim.Value = mapZLim;
            app.MapZWidthLab.Text = num2str(mapZWidth);
            app.MapZWidth.Value = mapZWidth;
            
            if strcmp(atomIon, 'atom')
               app.MapAtom.Value = 1;
            elseif strcmp(atomIon, 'ion')
               app.MapIon.Value = 1;
            end    
           
            app.MapIC_Raw.Value = 1;
            
           yyaxis(app.AnalysisStatisIm,'left')
           errorbar(app.AnalysisStatisIm,GBVal(1,:)-0.1,GBVal(2,:),GBVal(3,:))
           xlabel(app.AnalysisStatisIm,'GB Number')
           ylabel(app.AnalysisStatisIm,['Interfacial excess(' atomIon 's/nm^{2})'])
           axis(app.AnalysisStatisIm,'auto')
           xlim(app.AnalysisStatisIm,[min(GBVal(1,:))-1,max(GBVal(1,:))+1])
           hold(app.AnalysisStatisIm,'off')
           yyaxis(app.AnalysisStatisIm,'right')
           errorbar(app.AnalysisStatisIm,GBVal(1,:)+0.1,GBVal(4,:),GBVal(5,:));
           ylabel(app.AnalysisStatisIm,['Solute concentration(' atomIon '%)'])
           axis(app.AnalysisStatisIm,'auto')
           xlim(app.AnalysisStatisIm,[min(GBVal(1,:))-1,max(GBVal(1,:))+1])
           hold(app.AnalysisStatisIm,'off')
           
           trisurf(Fv.faces,Fv.vertices(:,1),Fv.vertices(:,2),Fv.vertices(:,3),Fv.Cs,'Parent',app.OverCIm)
           axis(app.OverCIm, 'equal')
           rotate3d(app.OverCIm,'on')
           shading(app.OverCIm,'interp')
           c = colorbar(app.OverCIm,'southoutside');
           c.Label.String = ['Concentration (' atomIon '%)'];
           axis(app.OverCIm,'tight');
           hold(app.OverCIm,'off')
           
           
           trisurf(Fv.faces,Fv.vertices(:,1),Fv.vertices(:,2),Fv.vertices(:,3),Fv.IEs,'Parent',app.OverIEIm)
           axis(app.OverIEIm, 'equal')
           rotate3d(app.OverIEIm,'on')
           shading(app.OverIEIm,'interp')
           c = colorbar(app.OverIEIm,'southoutside');
           c.Label.String = ['Interfacial excess (' atomIon 's/nm^{2})'];
           axis(app.OverIEIm,'tight');
           hold(app.OverIEIm,'off')
           
            GBNumItems = "";
            for i = 1:size(GBVal,2)
                 GBNumItems(i) = num2str(round(GBVal(1,i)));
            end
            
           app.IndiGBNum.Items = GBNumItems;
           app.LadderGBNum.Items = GBNumItems;
           
           
           app.OverCDw.Value = min(Fv.Cs);
           app.OverCUp.Value = max(Fv.Cs);
           app.OverIEDw.Value = min(Fv.IEs);
           app.OverIEUp.Value = max(Fv.IEs);
           
           
           Cdw = app.OverCDw.Value;
           Cup = app.OverCUp.Value; 
           IEdw = app.OverIEDw.Value;
           IEup = app.OverIEUp.Value;
           app.OverCIm.CLim = [Cdw,Cup];
           app.OverIEIm.CLim = [IEdw,IEup];
           
           app.GBAnaPanel.Visible = 1;
           app.AnalysisStatisIm.Visible = 1;
           app.MapIC_Smoothed.Enable = 1;
           app.MapIC_Raw.Enable = 1;
           app.IndiCNorIm.Visible = 0;
           app.IndiIENorIm.Visible = 0;
           app.IndiCIm.Visible = 0;
           app.IndiIEIm.Visible = 0;
           app.IndiSyncIE.Visible = 0;
           app.IndiSyncC.Visible = 0;
           app.LadderIm.Visible = 0;
           app.IndiCDw.Editable = 0;
           app.IndiCUp.Editable = 0;
           app.IndiIEDw.Editable = 0;
           app.IndiIEUp.Editable = 0;
           
           %app.AnalysisNote1.Visible = 1;
           %app.AnalysisNote2.Visible = 1;
           
           
           app.HisAllGBs.Value = 1;
           app.HisCBar.Value = 200;
           app.HisIEBar.Value = 200;
           
           Cs = Fv.Cs;
           IEs = Fv.IEs;
           
           app.HisArea.Value = 1;
           if app.HisArea.Value == 1
               Hweight = Fv.As;
               yLab = 'Area fraction';
           else
               Hweight = 1;
               yLab = 'Number fraction';
           end
           
           app.HisCDw.Value = min(Cs);
           app.HisCUp.Value = max(Cs);
           bin = min(Cs):(max(Cs)-min(Cs))/app.HisCBar.Value:max(Cs);
           bar(app.HisCIm,bin,histArea_R(Cs,bin,Hweight),'histc');
           xlabel(app.HisCIm,['Solute concentration(' atomIon ')%'])
           ylabel(app.HisCIm,yLab)
           axis(app.HisCIm,'auto')
           xlim(app.HisCIm,[app.HisCDw.Value,app.HisCUp.Value])
           
           app.HisIEDw.Value = min(IEs);
           app.HisIEUp.Value = max(IEs);
           bin = min(IEs):(max(IEs)-min(IEs))/app.HisIEBar.Value:max(IEs);
           bar(app.HisIEIm,bin,histArea_R(IEs,bin,Hweight),'histc');
           xlabel(app.HisIEIm,['Interfacial excess (' atomIon 's/nm^{2})'])
           ylabel(app.HisIEIm,yLab)
           axis(app.HisIEIm,'auto')
           xlim(app.HisIEIm,[app.HisIEDw.Value,app.HisIEUp.Value])
            
        end

        % Callback function
        function HisMethodSelectionChanged(app, event)
           
            
        end

        % Callback function
        function TrainingSegmentValueChanged(app, event)
            
        end

        % Button pushed function: LoadCNN
        function LoadCNNButtonPushed(app, event)
            global BW BWB BWTr cnnName pathPosName
            
            [cnnFileName, pathName] = uigetfile('*.txt','Select a file',[pathPosName '\CNN']);
            
            labelName = [pathName cnnFileName];
            
            cnnName = [pathName cnnFileName(1:end-4)];
            
            app.Project2DAllPanel.Visible = 1;
            app.Step3SubImagePanel.Visible = 1;
            
            app.Project2DPanel.Visible = 1;
            app.Proj2DGap.Enable = 1;
            app.Proj2DAll.Enable = 1;
            
            cnnFileNameSplit = strsplit(cnnFileName, '_');
            
            app.SubImVersion.Value = cnnFileNameSplit{1}(end);
           
            if strcmp(cnnFileNameSplit{2},'atom')
               app.ReducedAtom.Value = 1;
            else
               app.ReducedIon.Value = 1;
            end
            
            app.ReducedChem.Value = cnnFileNameSplit{3};
            app.Proj2DReso.Value = str2double(cnnFileNameSplit{4});
            app.Proj2DIonPercLab.Text = cnnFileNameSplit{5};
            app.Proj2DIFLab.Text = cnnFileNameSplit{6};
            app.Proj2DThetaSlider.Value = str2double(cnnFileNameSplit{7});
            app.SubImSize.Value = cnnFileNameSplit{8};
            app.SubImGap.Value = str2double(cnnFileNameSplit{9}(1:end-4));
            
            app.Proj2DOneButtonPushed()
            
            [BWB, BWTr] = chooseLabel_R(BW,str2double(app.SubImSize.Value),app.SubImGap.Value,labelName);
                       
            app.Step4GBLinePanel.Visible = 1;
            
            app.GBLineIF.Enable = 1;
            app.GBLineMaxL.Enable = 1;
            app.GBLineSkel.Enable = 1;
            app.GBLineTr1.Enable = 1;
            app.GBLineTr2.Enable = 1;
            app.GBLineMinL.Enable = 1;
            app.GBLineStyle.Enable =1;
            app.GBLineShow.Enable = 1;
            
            app.Step5GenerateGB.Visible = 1;
            app.FindGBPanel.Visible = 1;
            
            app.GBLineShowButtonPushed()
            
        end

        % Button pushed function: GenerateGBLoad
        function GenerateGBLoadButtonPushed(app, event)
            global BW BWB BWTr pathPosName
            
            [objFileName, pathName] = uigetfile('*.obj','Select a *.obj file',[pathPosName '\CNN']);
            
            objFileNameSplit = strsplit(objFileName, '_');
            objFileNameSplit1 = strsplit(objFileName, '__');
            labelName = [pathName objFileNameSplit1{1} '.txt'];
            
            app.SubImVersion.Value = objFileNameSplit{1}(end);
            app.GBLineStyle.Value = objFileNameSplit{10}(end);
           
            if strcmp(objFileNameSplit{2},'atom')
               app.ReducedAtom.Value = 1;
            else
               app.ReducedIon.Value = 1;
            end
            
            app.ReducedChem.Value = objFileNameSplit{3};
            app.Proj2DReso.Value = str2double(objFileNameSplit{4});
            app.Proj2DIonPercLab.Text = objFileNameSplit{5};
            app.Proj2DIFLab.Text = objFileNameSplit{6};
            app.Proj2DThetaSlider.Value = str2double(objFileNameSplit{7});
            app.Proj2DThetaSliderLab.Text = objFileNameSplit{7};
            app.SubImSize.Value = objFileNameSplit{8};
            app.SubImGap.Value = str2double(objFileNameSplit{9});
            
            app.Proj2DOneButtonPushed()
            
            [BWB, BWTr] = chooseLabel_R(BW,str2double(app.SubImSize.Value),app.SubImGap.Value,labelName);
            
            app.GBLineShowButtonPushed()
            
            app.GBLineIFLab.Text = objFileNameSplit{11};
            app.GBLineSkelLab.Text = objFileNameSplit{12};
            app.GBLineMaxLLab.Text = objFileNameSplit{13};
            app.GBLineMinLLab.Text = objFileNameSplit{14};
            app.GBLineTr1Lab.Text = objFileNameSplit{15};
            app.GBLineTr2Lab.Text = objFileNameSplit{16};
            
            app.GBLineIF.Value = str2double(objFileNameSplit{11});
            app.GBLineSkel.Value = str2double(objFileNameSplit{12});
            app.GBLineMaxL.Value = str2double(objFileNameSplit{13});
            app.GBLineMinL.Value = str2double(objFileNameSplit{14});
            app.GBLineTr1.Value = str2double(objFileNameSplit{15});
            app.GBLineTr2.Value = str2double(objFileNameSplit{16});
            
            if strcmp(objFileNameSplit{17}(1:end-4),'GB')
               app.GBLineShowLineSegRaw.Value = 1;
               app.GBLineShowLineSegCorr.Value = 0;
            else
               app.GBLineShowLineSegRaw.Value = 0;
               app.GBLineShowLineSegCorr.Value = 1;
            end
            
            app.GBLineShowButtonPushed()
            
        end

        % Button pushed function: OverSyncIE
        function OverSyncIEPushed(app, event)
             app.OverIEIm.XLim = app.OverCIm.XLim;
             app.OverIEIm.YLim = app.OverCIm.YLim;
             app.OverIEIm.CameraUpVector = app.OverCIm.CameraUpVector;
             app.OverIEIm.CameraPosition = app.OverCIm.CameraPosition;
             app.OverIEIm.CameraTarget = app.OverCIm.CameraTarget;
             %app.OverIEIm.CameraViewAngle = app.OverCIm.CameraViewAngle;
             
             app.OverCIm.XLim = app.OverIEIm.XLim;
             app.OverCIm.YLim = app.OverIEIm.YLim;
             app.OverCIm.CameraUpVector = app.OverIEIm.CameraUpVector;
             app.OverCIm.CameraPosition = app.OverIEIm.CameraPosition;
             app.OverCIm.CameraTarget = app.OverIEIm.CameraTarget;
             %app.OverCIm.CameraViewAngle = app.OverIEIm.CameraViewAngle;
        end

        % Button pushed function: OverSyncC
        function OverSyncCButtonPushed(app, event)
            app.OverCIm.XLim = app.OverIEIm.XLim;
            app.OverCIm.YLim = app.OverIEIm.YLim;
            app.OverCIm.CameraUpVector = app.OverIEIm.CameraUpVector;
            app.OverCIm.CameraPosition = app.OverIEIm.CameraPosition;
            app.OverCIm.CameraTarget = app.OverIEIm.CameraTarget;
            %app.OverCIm.CameraViewAngle = app.OverIEIm.CameraViewAngle;
            
            app.OverIEIm.XLim = app.OverCIm.XLim;
            app.OverIEIm.YLim = app.OverCIm.YLim;
            app.OverIEIm.CameraUpVector = app.OverCIm.CameraUpVector;
            app.OverIEIm.CameraPosition = app.OverCIm.CameraPosition;
            app.OverIEIm.CameraTarget = app.OverCIm.CameraTarget;
            %app.OverIEIm.CameraViewAngle = app.OverCIm.CameraViewAngle;
        end

        % Button pushed function: IndiSyncIE
        function IndiSyncIEButtonPushed(app, event)
            app.IndiIEIm.XLim = app.IndiCIm.XLim;
            app.IndiIEIm.YLim = app.IndiCIm.YLim;
            app.IndiIEIm.CameraUpVector = app.IndiCIm.CameraUpVector;
            app.IndiIEIm.CameraPosition = app.IndiCIm.CameraPosition;
            app.IndiIEIm.CameraTarget = app.IndiCIm.CameraTarget;
            %app.IndiIEIm.CameraViewAngle = app.IndiCIm.CameraViewAngle;
             
            app.IndiCIm.XLim = app.IndiIEIm.XLim;
            app.IndiCIm.YLim = app.IndiIEIm.YLim;
            app.IndiCIm.CameraUpVector = app.IndiIEIm.CameraUpVector;
            app.IndiCIm.CameraPosition = app.IndiIEIm.CameraPosition;
            app.IndiCIm.CameraTarget = app.IndiIEIm.CameraTarget;
            %app.IndiCIm.CameraViewAngle = app.IndiIEIm.CameraViewAngle;
        end

        % Button pushed function: IndiSyncC
        function IndiSyncCButtonPushed(app, event)
            app.IndiCIm.XLim = app.IndiIEIm.XLim;
            app.IndiCIm.YLim = app.IndiIEIm.YLim;
            app.IndiCIm.CameraUpVector = app.IndiIEIm.CameraUpVector;
            app.IndiCIm.CameraPosition = app.IndiIEIm.CameraPosition;
            app.IndiCIm.CameraTarget = app.IndiIEIm.CameraTarget;
            %app.IndiCIm.CameraViewAngle = app.IndiIEIm.CameraViewAngle;
            
            app.IndiIEIm.XLim = app.IndiCIm.XLim;
            app.IndiIEIm.YLim = app.IndiCIm.YLim;
            app.IndiIEIm.CameraUpVector = app.IndiCIm.CameraUpVector;
            app.IndiIEIm.CameraPosition = app.IndiCIm.CameraPosition;
            app.IndiIEIm.CameraTarget = app.IndiCIm.CameraTarget;
            %app.IndiIEIm.CameraViewAngle = app.IndiCIm.CameraViewAngle;
        end

        % Button pushed function: OpenBlenderButton
        function OpenBlenderButtonPushed(app, event)
            system('C:\Program Files\Blender Foundation\Blender 2.92\blender.exe');
        end

        % Value changed function: ReducedChem
        function ReducedChemValueChanged(app, event)
            app.MapChem.Value = app.ReducedChem.Value;
        end

        % Callback function
        function Proj2DIonPercValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APT_GBUIFigure = uifigure;
            app.APT_GBUIFigure.Name = 'APT_GB';
            app.TabGroup = uitabgroup(app.APT_GBUIFigure);
            app.Projection2DTab = uitab(app.TabGroup);
            app.Projection2DTab.Title = 'Projection 2D';
            app.Step1ImportDataPanel = uipanel(app.Projection2DTab);
            app.Step1ImportDataPanel.Title = 'Step 1: Import data';
            app.PosBottom = uibutton(app.Step1ImportDataPanel, 'push');
            app.PosBottom.ButtonPushedFcn = createCallbackFcn(app, @PosBottomPushed, true);
            app.PosBottom.Text = '*.POS';
            app.RRNGButton = uibutton(app.Step1ImportDataPanel, 'push');
            app.RRNGButton.ButtonPushedFcn = createCallbackFcn(app, @RRNGButtonPushed, true);
            app.RRNGButton.Text = '*.RRNG';
            app.Reduced = uibutton(app.Step1ImportDataPanel, 'push');
            app.Reduced.ButtonPushedFcn = createCallbackFcn(app, @ReducedPushed, true);
            app.Reduced.Text = 'Generate reduced atom map';
            app.ReducedpercentageLabel = uilabel(app.Step1ImportDataPanel);
            app.ReducedpercentageLabel.Text = {'Reduced'; 'percentage'};
            app.ReducedPerc = uislider(app.Step1ImportDataPanel);
            app.ReducedPerc.MajorTickLabels = {'0', '', '', '', '', '100'};
            app.ReducedPerc.ValueChangedFcn = createCallbackFcn(app, @ReducedPercValueChanged, true);
            app.ReducedPerc.MinorTicks = [];
            app.ReducedPerc.Value = 10;
            app.ReducedPercLab = uilabel(app.Step1ImportDataPanel);
            app.ReducedPercLab.Text = '20';
            app.ChemLabel = uilabel(app.Step1ImportDataPanel);
            app.ChemLabel.Text = 'Chem';
            app.ReducedChem = uidropdown(app.Step1ImportDataPanel);
            app.ReducedChem.Items = {};
            app.ReducedChem.Editable = 'on';
            app.ReducedChem.ValueChangedFcn = createCallbackFcn(app, @ReducedChemValueChanged, true);
            app.ReducedChem.Value = {};
            app.ReducedLight = uilamp(app.Step1ImportDataPanel);
            app.ReducedAtomIon = uibuttongroup(app.Step1ImportDataPanel);
            app.ReducedAtomIon.SelectionChangedFcn = createCallbackFcn(app, @ReducedAtomIonSelectionChanged, true);
            app.ReducedAtom = uiradiobutton(app.ReducedAtomIon);
            app.ReducedAtom.Text = 'Atom';
            app.ReducedAtom.Value = true;
            app.ReducedIon = uiradiobutton(app.ReducedAtomIon);
            app.ReducedIon.Text = 'Ion';
            app.Step2Proj2DPanel = uipanel(app.Projection2DTab);
            app.Step2Proj2DPanel.Title = 'Step 2: Generate 2D projection series';
            app.ResolutionnmEditFieldLabel = uilabel(app.Step2Proj2DPanel);
            app.ResolutionnmEditFieldLabel.Text = 'Resolution (nm)';
            app.Proj2DReso = uieditfield(app.Step2Proj2DPanel, 'numeric');
            app.Proj2DReso.ValueChangedFcn = createCallbackFcn(app, @Proj2DOneButtonPushed, true);
            app.Proj2DReso.Value = 0.4;
            app.Proj2DAll = uibutton(app.Step2Proj2DPanel, 'push');
            app.Proj2DAll.ButtonPushedFcn = createCallbackFcn(app, @Proj2DAllButtonPushed, true);
            app.Proj2DAll.Text = {'Calculate'; 'all '; '2D Images'};
            app.FilterLabel = uilabel(app.Step2Proj2DPanel);
            app.FilterLabel.Text = 'Filter';
            app.Proj2DIF = uislider(app.Step2Proj2DPanel);
            app.Proj2DIF.Limits = [-1 5];
            app.Proj2DIF.MajorTicks = [-1 0 1 2 3 4 5];
            app.Proj2DIF.MajorTickLabels = {'-1', '', '', '', '', '', '5'};
            app.Proj2DIF.ValueChangedFcn = createCallbackFcn(app, @Proj2DIFValueChanged, true);
            app.Proj2DIF.ValueChangingFcn = createCallbackFcn(app, @Proj2DOneButtonPushed, true);
            app.Proj2DIF.MinorTicks = [];
            app.Proj2DIF.Value = 0.5;
            app.Proj2DOne = uibutton(app.Step2Proj2DPanel, 'push');
            app.Proj2DOne.ButtonPushedFcn = createCallbackFcn(app, @Proj2DOneButtonPushed, true);
            app.Proj2DOne.Text = {'Calculate '; 'one'; '2D  image'};
            app.GapSliderLabel = uilabel(app.Step2Proj2DPanel);
            app.GapSliderLabel.Text = 'Gap';
            app.Proj2DGap = uislider(app.Step2Proj2DPanel);
            app.Proj2DGap.Limits = [0 90];
            app.Proj2DGap.MajorTicks = [0 30 60 90];
            app.Proj2DGap.MajorTickLabels = {'0', '', '', '90'};
            app.Proj2DGap.ValueChangedFcn = createCallbackFcn(app, @Proj2DGapValueChanged, true);
            app.Proj2DGap.MinorTicks = [];
            app.Proj2DGap.Value = 90;
            app.Proj2DIFLab = uilabel(app.Step2Proj2DPanel);
            app.Proj2DIFLab.Text = '0.5';
            app.Label = uilabel(app.Step2Proj2DPanel);
            app.Label.Text = '%';
            app.Proj2DIonPerc = uislider(app.Step2Proj2DPanel);
            app.Proj2DIonPerc.MajorTicks = [0 25 50 75 100];
            app.Proj2DIonPerc.MajorTickLabels = {'0', '', '', '', '100'};
            app.Proj2DIonPerc.ValueChangedFcn = createCallbackFcn(app, @Proj2DIonPercValueChanged, true);
            app.Proj2DIonPerc.ValueChangingFcn = createCallbackFcn(app, @Proj2DOneButtonPushed, true);
            app.Proj2DIonPerc.MinorTicks = [];
            app.Proj2DIonPerc.Value = 100;
            app.Proj2DGapLab = uilabel(app.Step2Proj2DPanel);
            app.Proj2DGapLab.Text = '90';
            app.Proj2DIonPercLab = uilabel(app.Step2Proj2DPanel);
            app.Proj2DIonPercLab.Text = '100';
            app.Step3SubImagePanel = uipanel(app.Projection2DTab);
            app.Step3SubImagePanel.Title = 'Step 3: Indentify features via machine learning';
            app.SubimagesizepixelDropDownLabel = uilabel(app.Step3SubImagePanel);
            app.SubimagesizepixelDropDownLabel.Text = 'Subimage size (pixel)';
            app.SubImSize = uidropdown(app.Step3SubImagePanel);
            app.SubImSize.Items = {'20'};
            app.SubImSize.Value = '20';
            app.SubImStore = uibutton(app.Step3SubImagePanel, 'push');
            app.SubImStore.ButtonPushedFcn = createCallbackFcn(app, @SubImStoreButtonPushed, true);
            app.SubImStore.Text = 'Export ';
            app.SubImSaveLab = uilabel(app.Step3SubImagePanel);
            app.SubImSaveLab.Text = 'idle...';
            app.SubImSaveLight = uilamp(app.Step3SubImagePanel);
            app.GappixelDropDownLabel = uilabel(app.Step3SubImagePanel);
            app.GappixelDropDownLabel.Text = 'Gap (pixel)';
            app.SubImGap = uidropdown(app.Step3SubImagePanel);
            app.SubImGap.Items = {'3'};
            app.SubImGap.Value = '3';
            app.SubImInformation = uilabel(app.Step3SubImagePanel);
            app.SubImInformation.Text = 'Large Gap reduces resolution of GB identification';
            app.VersionDropDownLabel = uilabel(app.Step3SubImagePanel);
            app.VersionDropDownLabel.Text = 'Version';
            app.SubImVersion = uidropdown(app.Step3SubImagePanel);
            app.SubImVersion.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SubImVersion.Value = '1';
            app.Project2DPanel = uipanel(app.Projection2DTab);
            app.Project2DPanel.Title = 'Project2D';
            app.Proj2DIm = uiaxes(app.Project2DPanel);
            title(app.Proj2DIm, '2D projection')
            xlabel(app.Proj2DIm, 'X (nm)')
            ylabel(app.Proj2DIm, 'Z (nm)')
            app.Proj2DICChoice = uibuttongroup(app.Project2DPanel);
            app.Proj2DICChoice.SelectionChangedFcn = createCallbackFcn(app, @Proj2DICChoiceSelectionChanged, true);
            app.Proj2DICChoice.Title = 'Imaging condition';
            app.Proj2DIC_Filtered = uiradiobutton(app.Proj2DICChoice);
            app.Proj2DIC_Filtered.Text = 'Filtered';
            app.Proj2DIC_Raw = uiradiobutton(app.Proj2DICChoice);
            app.Proj2DIC_Raw.Text = 'Raw';
            app.Proj2DIC_FilteredEdge = uiradiobutton(app.Proj2DICChoice);
            app.Proj2DIC_FilteredEdge.Text = 'Filtered Edge';
            app.Proj2DIC_FilteredEdge.Value = true;
            app.Project2DAllPanel = uipanel(app.Projection2DTab);
            app.Project2DAllPanel.Title = 'Project2D All';
            app.Proj2DSRChoice = uibuttongroup(app.Project2DAllPanel);
            app.Proj2DSRChoice.SelectionChangedFcn = createCallbackFcn(app, @Proj2DSRChoiceSelectionChanged, true);
            app.Proj2DSRChoice.Title = 'Selection rule';
            app.Proj2DSR_Both = uiradiobutton(app.Proj2DSRChoice);
            app.Proj2DSR_Both.Text = 'Both';
            app.Proj2DSR_Both.Value = true;
            app.Proj2DSR_Num = uiradiobutton(app.Proj2DSRChoice);
            app.Proj2DSR_Num.Text = 'Number';
            app.Proj2DSR_Con = uiradiobutton(app.Proj2DSRChoice);
            app.Proj2DSR_Con.Text = 'Concentration';
            app.PredictThetaIm = uiaxes(app.Project2DAllPanel);
            title(app.PredictThetaIm, 'Rotate the tip to find the best theta value')
            xlabel(app.PredictThetaIm, 'Theta (degree)')
            ylabel(app.PredictThetaIm, 'Selection factor')
            app.Proj2DThetaSliderLab = uilabel(app.Project2DAllPanel);
            app.Proj2DThetaSliderLab.Text = '0';
            app.SelectedThetadegreeLabel = uilabel(app.Project2DAllPanel);
            app.SelectedThetadegreeLabel.Text = 'Selected Theta (degree):';
            app.Proj2DThetaSlider = uislider(app.Project2DAllPanel);
            app.Proj2DThetaSlider.Limits = [0 360];
            app.Proj2DThetaSlider.MajorTicks = [0 90 180 270 360];
            app.Proj2DThetaSlider.ValueChangedFcn = createCallbackFcn(app, @Proj2DThetaSliderValueChanged, true);
            app.FindGBsTab = uitab(app.TabGroup);
            app.FindGBsTab.Title = 'Find GBs';
            app.Step4GBLinePanel = uipanel(app.FindGBsTab);
            app.Step4GBLinePanel.Title = 'Step 4: Show GB/Triple after machine learning';
            app.CleanGBSkeletonSliderLabel = uilabel(app.Step4GBLinePanel);
            app.CleanGBSkeletonSliderLabel.Text = {'Clean GB'; 'Skeleton'};
            app.GBLineSkel = uislider(app.Step4GBLinePanel);
            app.GBLineSkel.Limits = [0 15];
            app.GBLineSkel.MajorTicks = [0 3 6 9 12 15];
            app.GBLineSkel.MajorTickLabels = {'0', '', '', '', '', '15'};
            app.GBLineSkel.ValueChangedFcn = createCallbackFcn(app, @GBLineSkelValueChanged, true);
            app.GBLineSkel.ValueChangingFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineSkel.MinorTicks = [];
            app.GBLineSkel.Value = 6;
            app.TripleImageFilter1SliderLabel = uilabel(app.Step4GBLinePanel);
            app.TripleImageFilter1SliderLabel.Text = {'Triple Image'; ' Filter 1'};
            app.GBLineTr1 = uislider(app.Step4GBLinePanel);
            app.GBLineTr1.Limits = [1 4];
            app.GBLineTr1.MajorTicks = [1 2 3 4];
            app.GBLineTr1.MajorTickLabels = {'1', '', '', '4'};
            app.GBLineTr1.ValueChangedFcn = createCallbackFcn(app, @GBLineTr1ValueChanged, true);
            app.GBLineTr1.ValueChangingFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineTr1.MinorTicks = [];
            app.GBLineTr1.Value = 2;
            app.TripleImageFilter2SliderLabel = uilabel(app.Step4GBLinePanel);
            app.TripleImageFilter2SliderLabel.Text = {'Triple Image'; ' Filter 2'};
            app.GBLineTr2 = uislider(app.Step4GBLinePanel);
            app.GBLineTr2.Limits = [5 20];
            app.GBLineTr2.MajorTicks = [5 10 15 20];
            app.GBLineTr2.MajorTickLabels = {'5', '', '', '20'};
            app.GBLineTr2.ValueChangedFcn = createCallbackFcn(app, @GBLineTr2ValueChanged, true);
            app.GBLineTr2.ValueChangingFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineTr2.MinorTicks = [];
            app.GBLineTr2.Value = 10;
            app.MinimumLengthnmLabel = uilabel(app.Step4GBLinePanel);
            app.MinimumLengthnmLabel.Text = {'Minimum'; 'Length(nm)'};
            app.GBLineMinL = uislider(app.Step4GBLinePanel);
            app.GBLineMinL.Limits = [0 1];
            app.GBLineMinL.MajorTicks = [0 0.33 0.66 1];
            app.GBLineMinL.MajorTickLabels = {'0', '', '', '1'};
            app.GBLineMinL.ValueChangedFcn = createCallbackFcn(app, @GBLineMinLValueChanged, true);
            app.GBLineMinL.ValueChangingFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineMinL.MinorTicks = [];
            app.GBLineShow = uibutton(app.Step4GBLinePanel, 'push');
            app.GBLineShow.ButtonPushedFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineShow.Text = 'Show GB/Triple images';
            app.GBLineIFLab = uilabel(app.Step4GBLinePanel);
            app.GBLineIFLab.Text = '1';
            app.GBLineSkelLab = uilabel(app.Step4GBLinePanel);
            app.GBLineSkelLab.Text = '6';
            app.GBLineTr1Lab = uilabel(app.Step4GBLinePanel);
            app.GBLineTr1Lab.Text = '2';
            app.GBLineTr2Lab = uilabel(app.Step4GBLinePanel);
            app.GBLineTr2Lab.Text = '10';
            app.GBLineMinLLab = uilabel(app.Step4GBLinePanel);
            app.GBLineMinLLab.Text = '0';
            app.GBImageFilterLabel = uilabel(app.Step4GBLinePanel);
            app.GBImageFilterLabel.Text = {'GB Image'; 'Filter'};
            app.GBLineIF = uislider(app.Step4GBLinePanel);
            app.GBLineIF.Limits = [-1 5];
            app.GBLineIF.MajorTicks = [-1 0 1 2 3 4 5];
            app.GBLineIF.MajorTickLabels = {'-1', '', '', '', '', '', '5'};
            app.GBLineIF.ValueChangedFcn = createCallbackFcn(app, @GBLineIFValueChanged, true);
            app.GBLineIF.ValueChangingFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineIF.MinorTicks = [];
            app.GBLineIF.Value = 1;
            app.GBLineMaxLLab = uilabel(app.Step4GBLinePanel);
            app.GBLineMaxLLab.Text = '1';
            app.MaximumLengthnmLabel = uilabel(app.Step4GBLinePanel);
            app.MaximumLengthnmLabel.Text = {'Maximum'; 'Length (nm)'};
            app.GBLineMaxL = uislider(app.Step4GBLinePanel);
            app.GBLineMaxL.Limits = [0 1];
            app.GBLineMaxL.MajorTicks = [0 0.33 0.66 1];
            app.GBLineMaxL.MajorTickLabels = {'0', '', '', '1'};
            app.GBLineMaxL.ValueChangedFcn = createCallbackFcn(app, @GBLineMaxLValueChanged, true);
            app.GBLineMaxL.ValueChangingFcn = createCallbackFcn(app, @GBLineShowButtonPushed, true);
            app.GBLineMaxL.MinorTicks = [];
            app.GBLineMaxL.Value = 1;
            app.StyleLabel = uilabel(app.Step4GBLinePanel);
            app.StyleLabel.Text = 'Style:';
            app.GBLineStyle = uidropdown(app.Step4GBLinePanel);
            app.GBLineStyle.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.GBLineStyle.Value = '1';
            app.Step5GenerateGB = uipanel(app.FindGBsTab);
            app.Step5GenerateGB.Title = 'Step 5: Generate GB Mesh';
            app.GenerateGBOBJ = uibutton(app.Step5GenerateGB, 'push');
            app.GenerateGBOBJ.ButtonPushedFcn = createCallbackFcn(app, @GenerateGBOBJPushed, true);
            app.GenerateGBOBJ.Text = {'Choose a *.obj file to'; 'export initial GB planes'};
            app.GenerateGBLoad = uibutton(app.Step5GenerateGB, 'push');
            app.GenerateGBLoad.ButtonPushedFcn = createCallbackFcn(app, @GenerateGBLoadButtonPushed, true);
            app.GenerateGBLoad.Text = {'Load an'; 'existing file'};
            app.OpenBlenderButton = uibutton(app.Step5GenerateGB, 'push');
            app.OpenBlenderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBlenderButtonPushed, true);
            app.OpenBlenderButton.Text = 'Open Blender';
            app.FindGBPanel = uipanel(app.FindGBsTab);
            app.FindGBPanel.Title = 'Find GB';
            app.GBLineGBIm = uiaxes(app.FindGBPanel);
            title(app.GBLineGBIm, 'Grain boundary 2D projection')
            xlabel(app.GBLineGBIm, 'X (nm)')
            ylabel(app.GBLineGBIm, 'Z (nm)')
            app.GBLineTrIm = uiaxes(app.FindGBPanel);
            title(app.GBLineTrIm, 'Triple junction 2D projection/Concentration')
            xlabel(app.GBLineTrIm, 'X (nm)')
            ylabel(app.GBLineTrIm, 'Z (nm)')
            app.GBLineGBIC = uibuttongroup(app.FindGBPanel);
            app.GBLineGBIC.SelectionChangedFcn = createCallbackFcn(app, @GBLineGBICSelectionChanged, true);
            app.GBLineGBIC.Title = 'GB Imaging condition';
            app.GBLineGBIC_Raw = uiradiobutton(app.GBLineGBIC);
            app.GBLineGBIC_Raw.Text = 'Raw';
            app.GBLineGBIC_BW = uiradiobutton(app.GBLineGBIC);
            app.GBLineGBIC_BW.Text = 'Filtered';
            app.GBLineGBIC_BW.Value = true;
            app.GBLineGBIC_Skel = uiradiobutton(app.GBLineGBIC);
            app.GBLineGBIC_Skel.Text = 'Skeleton';
            app.GBLineGBIC_GBLines = uiradiobutton(app.GBLineGBIC);
            app.GBLineGBIC_GBLines.Text = 'GB lines';
            app.GBLineShowLineSegPanel = uipanel(app.FindGBPanel);
            app.GBLineShowLineSegPanel.Title = 'Options';
            app.GBLineShowLineSegRaw = uicheckbox(app.GBLineShowLineSegPanel);
            app.GBLineShowLineSegRaw.ValueChangedFcn = createCallbackFcn(app, @GBLineShowLineSegRawValueChanged, true);
            app.GBLineShowLineSegRaw.Text = 'Show raw GB Segments';
            app.GBLineShowLineSegCorr = uicheckbox(app.GBLineShowLineSegPanel);
            app.GBLineShowLineSegCorr.ValueChangedFcn = createCallbackFcn(app, @GBLineShowLineSegCorrValueChanged, true);
            app.GBLineShowLineSegCorr.Text = {'Show Triple junction '; 'corrected GB Segments'};
            app.GBLineShowLineSegCorr.Value = true;
            app.GBLineTrIC = uibuttongroup(app.FindGBPanel);
            app.GBLineTrIC.SelectionChangedFcn = createCallbackFcn(app, @GBLineTrICSelectionChanged, true);
            app.GBLineTrIC.Title = 'Triple Imaging condition/Concentration';
            app.GBLineTrIC_Raw = uiradiobutton(app.GBLineTrIC);
            app.GBLineTrIC_Raw.Text = 'Raw';
            app.GBLineTrIC_Filtered = uiradiobutton(app.GBLineTrIC);
            app.GBLineTrIC_Filtered.Text = 'Filtered';
            app.GBLineTrIC_Con = uiradiobutton(app.GBLineTrIC);
            app.GBLineTrIC_Con.Text = 'Concentration';
            app.GBLineTrIC_Con.Value = true;
            app.GBLineTrIC_None = uiradiobutton(app.GBLineTrIC);
            app.GBLineTrIC_None.Text = 'None';
            app.LoadCNN = uibutton(app.FindGBsTab, 'push');
            app.LoadCNN.ButtonPushedFcn = createCallbackFcn(app, @LoadCNNButtonPushed, true);
            app.LoadCNN.Text = 'Load existing machine learning results';
            app.GBAnalysisTab = uitab(app.TabGroup);
            app.GBAnalysisTab.Title = 'GB Analysis';
            app.Step6MappingPanel = uipanel(app.GBAnalysisTab);
            app.Step6MappingPanel.Title = 'Step 6: Mapping';
            app.MapCal = uibutton(app.Step6MappingPanel, 'push');
            app.MapCal.ButtonPushedFcn = createCallbackFcn(app, @MapCalButtonPushed, true);
            app.MapCal.Text = 'Cal.';
            app.ZLimitnmSliderLabel = uilabel(app.Step6MappingPanel);
            app.ZLimitnmSliderLabel.Text = {'Z Limit'; '(nm)'};
            app.MapZLim = uislider(app.Step6MappingPanel);
            app.MapZLim.Limits = [5 40];
            app.MapZLim.MajorTicks = [5 14 23 32 40];
            app.MapZLim.MajorTickLabels = {'5', '', '', '', '40'};
            app.MapZLim.ValueChangedFcn = createCallbackFcn(app, @MapZLimValueChanged, true);
            app.MapZLim.MinorTicks = [];
            app.MapZLim.Value = 15;
            app.ZwidthnmSliderLabel = uilabel(app.Step6MappingPanel);
            app.ZwidthnmSliderLabel.Text = {'Z width'; '(nm)'};
            app.MapZWidth = uislider(app.Step6MappingPanel);
            app.MapZWidth.Limits = [1 4];
            app.MapZWidth.MajorTicks = [1 2 3 4];
            app.MapZWidth.MajorTickLabels = {'1', '', '', '4'};
            app.MapZWidth.ValueChangedFcn = createCallbackFcn(app, @MapZWidthValueChanged, true);
            app.MapZWidth.MinorTicks = [];
            app.MapZWidth.Value = 1;
            app.MapZLimLab = uilabel(app.Step6MappingPanel);
            app.MapZLimLab.Text = '15';
            app.MapZWidthLab = uilabel(app.Step6MappingPanel);
            app.MapZWidthLab.Text = '1';
            app.ChemLabel_2 = uilabel(app.Step6MappingPanel);
            app.ChemLabel_2.Text = 'Chem';
            app.MapChem = uidropdown(app.Step6MappingPanel);
            app.MapChem.Items = {};
            app.MapChem.Editable = 'on';
            app.MapChem.Value = {};
            app.MapIC = uibuttongroup(app.Step6MappingPanel);
            app.MapIC.SelectionChangedFcn = createCallbackFcn(app, @MapICSelectionChanged, true);
            app.MapIC.Title = 'Imaging';
            app.MapIC_Smoothed = uiradiobutton(app.MapIC);
            app.MapIC_Smoothed.Text = 'Smoothed';
            app.MapIC_Raw = uiradiobutton(app.MapIC);
            app.MapIC_Raw.Text = 'Raw';
            app.MapIC_Raw.Value = true;
            app.MapStatusLab = uilabel(app.Step6MappingPanel);
            app.MapStatusLab.Text = 'idle...';
            app.MapStatusLight = uilamp(app.Step6MappingPanel);
            app.MapObjFile = uibutton(app.Step6MappingPanel, 'push');
            app.MapObjFile.ButtonPushedFcn = createCallbackFcn(app, @MapObjFileButtonPushed, true);
            app.MapObjFile.Text = {'Select *.obj'; 'GB mesh file'};
            app.SystemTypeDropDownLabel = uilabel(app.Step6MappingPanel);
            app.SystemTypeDropDownLabel.Text = 'System Type';
            app.MapSysType = uidropdown(app.Step6MappingPanel);
            app.MapSysType.Items = {'5000 XS', '5000 XR/3000 XS/4000 XS', '3000 HR/4000 HR', 'Synthetic data'};
            app.MapSysType.Value = '5000 XS';
            app.MapAtomIon = uibuttongroup(app.Step6MappingPanel);
            app.MapAtomIon.SelectionChangedFcn = createCallbackFcn(app, @MapAtomIonSelectionChanged, true);
            app.MapAtom = uiradiobutton(app.MapAtomIon);
            app.MapAtom.Text = 'Atom';
            app.MapAtom.Value = true;
            app.MapIon = uiradiobutton(app.MapAtomIon);
            app.MapIon.Text = 'Ion';
            app.MapLoad = uibutton(app.Step6MappingPanel, 'push');
            app.MapLoad.ButtonPushedFcn = createCallbackFcn(app, @MapLoadPushed, true);
            app.MapLoad.Text = 'Load';
            app.Panel = uipanel(app.GBAnalysisTab);
            app.GBAnaPanel = uitabgroup(app.Panel);
            app.OverviewTab = uitab(app.GBAnaPanel);
            app.OverviewTab.Title = 'Overview';
            app.OverIESave = uibutton(app.OverviewTab, 'push');
            app.OverIESave.ButtonPushedFcn = createCallbackFcn(app, @OverIESaveButtonPushed, true);
            app.OverIESave.Text = 'Save IE map';
            app.UpEditFieldLabel = uilabel(app.OverviewTab);
            app.UpEditFieldLabel.Text = 'Up';
            app.OverCUp = uieditfield(app.OverviewTab, 'numeric');
            app.OverCUp.ValueDisplayFormat = '%.2f';
            app.OverCUp.ValueChangedFcn = createCallbackFcn(app, @OverCUpValueChanged, true);
            app.OverCDw = uieditfield(app.OverviewTab, 'numeric');
            app.OverCDw.ValueDisplayFormat = '%.2f';
            app.OverCDw.ValueChangedFcn = createCallbackFcn(app, @OverCDwValueChanged, true);
            app.ColorDwEditFieldLabel = uilabel(app.OverviewTab);
            app.ColorDwEditFieldLabel.Text = 'Color Dw';
            app.OverCSave = uibutton(app.OverviewTab, 'push');
            app.OverCSave.ButtonPushedFcn = createCallbackFcn(app, @OverCSaveButtonPushed, true);
            app.OverCSave.Text = 'Save C map';
            app.ColorDwEditFieldLabel_2 = uilabel(app.OverviewTab);
            app.ColorDwEditFieldLabel_2.Text = 'Color Dw';
            app.OverIEDw = uieditfield(app.OverviewTab, 'numeric');
            app.OverIEDw.ValueDisplayFormat = '%.2f';
            app.OverIEDw.ValueChangedFcn = createCallbackFcn(app, @OverIEDwValueChanged, true);
            app.UpEditFieldLabel_2 = uilabel(app.OverviewTab);
            app.UpEditFieldLabel_2.Text = 'Up';
            app.OverIEUp = uieditfield(app.OverviewTab, 'numeric');
            app.OverIEUp.ValueDisplayFormat = '%.2f';
            app.OverIEUp.ValueChangedFcn = createCallbackFcn(app, @OverIEUpValueChanged, true);
            app.OverIEIm = uiaxes(app.OverviewTab);
            title(app.OverIEIm, 'IE map')
            xlabel(app.OverIEIm, 'X (nm)')
            ylabel(app.OverIEIm, 'Y (nm)')
            app.OverCIm = uiaxes(app.OverviewTab);
            title(app.OverCIm, 'C map')
            xlabel(app.OverCIm, 'X (nm)')
            ylabel(app.OverCIm, 'Y (nm)')
            app.OverSyncIE = uibutton(app.OverviewTab, 'push');
            app.OverSyncIE.ButtonPushedFcn = createCallbackFcn(app, @OverSyncIEPushed, true);
            app.OverSyncIE.Text = 'Sync IE';
            app.OverSyncC = uibutton(app.OverviewTab, 'push');
            app.OverSyncC.ButtonPushedFcn = createCallbackFcn(app, @OverSyncCButtonPushed, true);
            app.OverSyncC.Text = 'Sync C';
            app.SelectedGBsTab = uitab(app.GBAnaPanel);
            app.SelectedGBsTab.Title = 'Selected GB(s)';
            app.IndiIC = uibuttongroup(app.SelectedGBsTab);
            app.IndiIC.SelectionChangedFcn = createCallbackFcn(app, @IndiICSelectionChanged, true);
            app.IndiIC.Title = 'Imaging condition';
            app.IndiIC_Smoothed = uiradiobutton(app.IndiIC);
            app.IndiIC_Smoothed.Text = 'Smoothed';
            app.IndiIC_Raw = uiradiobutton(app.IndiIC);
            app.IndiIC_Raw.Text = 'Raw';
            app.IndiIC_Raw.Value = true;
            app.IndiCal = uibutton(app.SelectedGBsTab, 'push');
            app.IndiCal.ButtonPushedFcn = createCallbackFcn(app, @IndiCalButtonPushed, true);
            app.IndiCal.Text = 'Cal.';
            app.GBNumbersDropDownLabel = uilabel(app.SelectedGBsTab);
            app.GBNumbersDropDownLabel.Text = 'GB Number(s)';
            app.IndiGBNum = uidropdown(app.SelectedGBsTab);
            app.IndiGBNum.Items = {};
            app.IndiGBNum.Editable = 'on';
            app.IndiGBNum.Value = {};
            app.TabGroup3 = uitabgroup(app.SelectedGBsTab);
            app.NormalTab_2 = uitab(app.TabGroup3);
            app.NormalTab_2.Title = 'Normal';
            app.IndiIENorIm = uiaxes(app.NormalTab_2);
            title(app.IndiIENorIm, 'IE Normal')
            xlabel(app.IndiIENorIm, 'X (nm)')
            ylabel(app.IndiIENorIm, 'Y (nm)')
            app.IndiCNorIm = uiaxes(app.NormalTab_2);
            title(app.IndiCNorIm, 'C Map Normal')
            xlabel(app.IndiCNorIm, 'X (nm)')
            ylabel(app.IndiCNorIm, 'Y (nm)')
            app.LandscaleTab = uitab(app.TabGroup3);
            app.LandscaleTab.Title = 'Landscale';
            app.IndiIEIm = uiaxes(app.LandscaleTab);
            title(app.IndiIEIm, 'IE Map')
            xlabel(app.IndiIEIm, 'X (nm)')
            ylabel(app.IndiIEIm, 'Y (nm)')
            app.IndiCIm = uiaxes(app.LandscaleTab);
            title(app.IndiCIm, 'C Map')
            xlabel(app.IndiCIm, 'X (nm)')
            ylabel(app.IndiCIm, 'Y (nm)')
            app.IndiSyncIE = uibutton(app.LandscaleTab, 'push');
            app.IndiSyncIE.ButtonPushedFcn = createCallbackFcn(app, @IndiSyncIEButtonPushed, true);
            app.IndiSyncIE.Text = 'Sync IE';
            app.IndiSyncC = uibutton(app.LandscaleTab, 'push');
            app.IndiSyncC.ButtonPushedFcn = createCallbackFcn(app, @IndiSyncCButtonPushed, true);
            app.IndiSyncC.Text = 'Sync C';
            app.IndiCDw = uieditfield(app.SelectedGBsTab, 'numeric');
            app.IndiCDw.ValueDisplayFormat = '%.2f';
            app.IndiCDw.ValueChangedFcn = createCallbackFcn(app, @IndiCDwValueChanged, true);
            app.CmapColorDwLabel = uilabel(app.SelectedGBsTab);
            app.CmapColorDwLabel.Text = 'C map Color Dw';
            app.UpEditFieldLabel_3 = uilabel(app.SelectedGBsTab);
            app.UpEditFieldLabel_3.Text = 'Up';
            app.IndiCUp = uieditfield(app.SelectedGBsTab, 'numeric');
            app.IndiCUp.ValueDisplayFormat = '%.2f';
            app.IndiCUp.ValueChangedFcn = createCallbackFcn(app, @IndiCUpValueChanged, true);
            app.IndiIEDw = uieditfield(app.SelectedGBsTab, 'numeric');
            app.IndiIEDw.ValueDisplayFormat = '%.2f';
            app.IndiIEDw.ValueChangedFcn = createCallbackFcn(app, @IndiIEDwValueChanged, true);
            app.IEmapColorDwLabel = uilabel(app.SelectedGBsTab);
            app.IEmapColorDwLabel.Text = 'IE map Color Dw';
            app.UpEditFieldLabel_4 = uilabel(app.SelectedGBsTab);
            app.UpEditFieldLabel_4.Text = 'Up';
            app.IndiIEUp = uieditfield(app.SelectedGBsTab, 'numeric');
            app.IndiIEUp.ValueDisplayFormat = '%.2f';
            app.IndiIEUp.ValueChangedFcn = createCallbackFcn(app, @IndiIEUpValueChanged, true);
            app.IndiSave = uibutton(app.SelectedGBsTab, 'push');
            app.IndiSave.ButtonPushedFcn = createCallbackFcn(app, @IndiSaveButtonPushed, true);
            app.IndiSave.Text = 'Save';
            app.HistogramTab = uitab(app.GBAnaPanel);
            app.HistogramTab.Title = 'Histogram';
            app.HisCIm = uiaxes(app.HistogramTab);
            title(app.HisCIm, 'C historgram')
            xlabel(app.HisCIm, 'X')
            ylabel(app.HisCIm, 'Y')
            app.HisIEIm = uiaxes(app.HistogramTab);
            title(app.HisIEIm, 'IE histogram')
            xlabel(app.HisIEIm, 'X')
            ylabel(app.HisIEIm, 'Y')
            app.HisCDw = uieditfield(app.HistogramTab, 'numeric');
            app.HisCDw.ValueDisplayFormat = '%.2f';
            app.HisCDw.ValueChangedFcn = createCallbackFcn(app, @HisCBarValueChanged, true);
            app.DwLabel = uilabel(app.HistogramTab);
            app.DwLabel.Text = 'Dw';
            app.UpEditFieldLabel_5 = uilabel(app.HistogramTab);
            app.UpEditFieldLabel_5.Text = 'Up';
            app.HisCUp = uieditfield(app.HistogramTab, 'numeric');
            app.HisCUp.ValueDisplayFormat = '%.2f';
            app.HisCUp.ValueChangedFcn = createCallbackFcn(app, @HisCBarValueChanged, true);
            app.HisSave = uibutton(app.HistogramTab, 'push');
            app.HisSave.ButtonPushedFcn = createCallbackFcn(app, @HisSaveButtonPushed, true);
            app.HisSave.Text = 'Save';
            app.HisCBar = uieditfield(app.HistogramTab, 'numeric');
            app.HisCBar.ValueDisplayFormat = '%.0f';
            app.HisCBar.ValueChangedFcn = createCallbackFcn(app, @HisCBarValueChanged, true);
            app.HisCBar.Value = 40;
            app.CbarLabel = uilabel(app.HistogramTab);
            app.CbarLabel.Text = 'C bar';
            app.HisIEDw = uieditfield(app.HistogramTab, 'numeric');
            app.HisIEDw.ValueDisplayFormat = '%.2f';
            app.HisIEDw.ValueChangedFcn = createCallbackFcn(app, @HisIEBarValueChanged, true);
            app.DwLabel_2 = uilabel(app.HistogramTab);
            app.DwLabel_2.Text = 'Dw';
            app.UpEditFieldLabel_6 = uilabel(app.HistogramTab);
            app.UpEditFieldLabel_6.Text = 'Up';
            app.HisIEUp = uieditfield(app.HistogramTab, 'numeric');
            app.HisIEUp.ValueDisplayFormat = '%.2f';
            app.HisIEUp.ValueChangedFcn = createCallbackFcn(app, @HisIEBarValueChanged, true);
            app.HisIEBar = uieditfield(app.HistogramTab, 'numeric');
            app.HisIEBar.ValueDisplayFormat = '%.0f';
            app.HisIEBar.ValueChangedFcn = createCallbackFcn(app, @HisIEBarValueChanged, true);
            app.HisIEBar.Value = 40;
            app.IEbarLabel = uilabel(app.HistogramTab);
            app.IEbarLabel.Text = 'IE bar';
            app.HisType = uibuttongroup(app.HistogramTab);
            app.HisType.SelectionChangedFcn = createCallbackFcn(app, @HisTypeSelectionChanged, true);
            app.HisAllGBs = uiradiobutton(app.HisType);
            app.HisAllGBs.Text = 'All GBs';
            app.HisAllGBs.Value = true;
            app.HisIndiGBs = uiradiobutton(app.HisType);
            app.HisIndiGBs.Text = 'Individual GB(s)';
            app.HisMethod = uibuttongroup(app.HistogramTab);
            app.HisMethod.SelectionChangedFcn = createCallbackFcn(app, @HisTypeSelectionChanged, true);
            app.HisNum = uiradiobutton(app.HisMethod);
            app.HisNum.Text = 'Number fraction';
            app.HisArea = uiradiobutton(app.HisMethod);
            app.HisArea.Text = 'Area fraction';
            app.HisArea.Value = true;
            app.LadderdiagramTab = uitab(app.GBAnaPanel);
            app.LadderdiagramTab.Title = 'Ladder diagram';
            app.GBNumberLabel = uilabel(app.LadderdiagramTab);
            app.GBNumberLabel.Text = 'GB Number';
            app.LadderGBNum = uidropdown(app.LadderdiagramTab);
            app.LadderGBNum.Items = {};
            app.LadderGBNum.ValueChangedFcn = createCallbackFcn(app, @LadderPlotButtonPushed, true);
            app.LadderGBNum.Value = {};
            app.LadderPlot = uibutton(app.LadderdiagramTab, 'push');
            app.LadderPlot.ButtonPushedFcn = createCallbackFcn(app, @LadderPlotButtonPushed, true);
            app.LadderPlot.Text = 'Plot';
            app.ZoomleftclickinShiftleftclickoutORScrollwheelLabel = uilabel(app.LadderdiagramTab);
            app.ZoomleftclickinShiftleftclickoutORScrollwheelLabel.Text = {'Zoom: left click (in) /  "Shift" + '; 'left click (out) OR Scroll wheel '};
            app.LadderZoom = uiswitch(app.LadderdiagramTab, 'slider');
            app.LadderZoom.ValueChangedFcn = createCallbackFcn(app, @LadderZoomValueChanged, true);
            app.LadderZoom.Value = 'On';
            app.LadderIm = uiaxes(app.LadderdiagramTab);
            title(app.LadderIm, 'Ladder plots')
            app.AnalysisStatisIm = uiaxes(app.GBAnalysisTab);
            title(app.AnalysisStatisIm, 'Statistic IE and C values')
            xlabel(app.AnalysisStatisIm, 'X')
            ylabel(app.AnalysisStatisIm, 'Y')
            app.PrepareTrainingDatasetOptionalTab = uitab(app.TabGroup);
            app.PrepareTrainingDatasetOptionalTab.Title = 'Prepare Training Dataset (Optional)';
            app.Training = uibutton(app.PrepareTrainingDatasetOptionalTab, 'push');
            app.Training.ButtonPushedFcn = createCallbackFcn(app, @TrainingPushed, true);
            app.Training.Text = 'Only needs to be done once, it works for all data sets';
            app.NumberofGBstructureunitsEditFieldLabel = uilabel(app.PrepareTrainingDatasetOptionalTab);
            app.NumberofGBstructureunitsEditFieldLabel.Text = 'Number of GB structure units ';
            app.TrainingNumOfGBnet = uieditfield(app.PrepareTrainingDatasetOptionalTab, 'numeric');
            app.TrainingNumOfGBnet.ValueChangedFcn = createCallbackFcn(app, @TrainingNumOfGBnetValueChanged, true);
            app.TrainingNumOfGBnet.Value = 15;
            app.RestartpointofGBstructureunitsEditFieldLabel = uilabel(app.PrepareTrainingDatasetOptionalTab);
            app.RestartpointofGBstructureunitsEditFieldLabel.Text = 'Restart point of GB structure units';
            app.TrainingNumOfGBnetRestar = uieditfield(app.PrepareTrainingDatasetOptionalTab, 'numeric');
            app.TrainingNumOfGBnetRestar.ValueChangedFcn = createCallbackFcn(app, @TrainingNumOfGBnetRestarValueChanged, true);
            app.TrainingNumOfGBnetRestar.Value = 1;
            app.NumberofTrainingTestinggroupsDropDownLabel = uilabel(app.PrepareTrainingDatasetOptionalTab);
            app.NumberofTrainingTestinggroupsDropDownLabel.Text = 'Number of "Training + Testing" groups';
            app.TrainingSegment = uidropdown(app.PrepareTrainingDatasetOptionalTab);
            app.TrainingSegment.Items = {'1'};
            app.TrainingSegment.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = runMe

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APT_GBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APT_GBUIFigure)
        end
    end
end